classdef plot_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ParametersLabel                 matlab.ui.control.Label
        spring                          matlab.ui.container.TabGroup
        linear_spring                   matlab.ui.container.Tab
        kLabel                          matlab.ui.control.Label
        linear_spring_k                 matlab.ui.control.NumericEditField
        massEditFieldLabel_4            matlab.ui.control.Label
        linear_spring_mass              matlab.ui.control.NumericEditField
        FmaxLabel_3                     matlab.ui.control.Label
        linear_spring_Fmax              matlab.ui.control.NumericEditField
        linear_elastic_extensional_spring  matlab.ui.container.Tab
        ELabel                          matlab.ui.control.Label
        lee_spring_E                    matlab.ui.control.NumericEditField
        rhoLabel                        matlab.ui.control.Label
        lee_spring_rho                  matlab.ui.control.NumericEditField
        ALabel                          matlab.ui.control.Label
        lee_spring_A                    matlab.ui.control.NumericEditField
        LLabel                          matlab.ui.control.Label
        lee_spring_L                    matlab.ui.control.NumericEditField
        sigma_fLabel                    matlab.ui.control.Label
        lee_spring_sigma_f              matlab.ui.control.NumericEditField
        exponential_spring              matlab.ui.container.Tab
        k_0Label                        matlab.ui.control.Label
        exp_spring_k                    matlab.ui.control.NumericEditField
        characteristiclengthLabel       matlab.ui.control.Label
        exp_spring_char_len             matlab.ui.control.NumericEditField
        FmaxLabel_4                     matlab.ui.control.Label
        exp_spring_Fmax                 matlab.ui.control.NumericEditField
        massEditFieldLabel_5            matlab.ui.control.Label
        exp_spring_mass                 matlab.ui.control.NumericEditField
        loading_motor                   matlab.ui.container.TabGroup
        lm_linear_motor                 matlab.ui.container.Tab
        FmaxLabel_2                     matlab.ui.control.Label
        lm_linear_motor_Fmax            matlab.ui.control.NumericEditField
        VmaxLabel_2                     matlab.ui.control.Label
        lm_linear_motor_Vmax            matlab.ui.control.NumericEditField
        rangeofmotionLabel              matlab.ui.control.Label
        lm_linear_motor_range_of_motion  matlab.ui.control.NumericEditField
        voltagefracLabel                matlab.ui.control.Label
        lm_linear_motor_voltage_frac    matlab.ui.control.NumericEditField
        lm_hill_muscle_motor            matlab.ui.container.Tab
        FmaxLabel                       matlab.ui.control.Label
        lm_hill_motor_Fmax              matlab.ui.control.NumericEditField
        VmaxLabel                       matlab.ui.control.Label
        lm_hill_motor_Vmax              matlab.ui.control.NumericEditField
        musclelengthLabel               matlab.ui.control.Label
        lm_hill_motor_muscle_length     matlab.ui.control.NumericEditField
        rateofactivationLabel           matlab.ui.control.Label
        lm_hill_motor_rate_of_activation  matlab.ui.control.NumericEditField
        initiallengthLabel              matlab.ui.control.Label
        lm_hill_motor_L_i               matlab.ui.control.NumericEditField
        a_LLabel                        matlab.ui.control.Label
        lm_hill_motor_a_L               matlab.ui.control.NumericEditField
        b_LLabel                        matlab.ui.control.Label
        lm_hill_motor_b_L               matlab.ui.control.NumericEditField
        sLabel                          matlab.ui.control.Label
        lm_hill_motor_s                 matlab.ui.control.NumericEditField
        latch                           matlab.ui.container.TabGroup
        rounded_latch                   matlab.ui.container.Tab
        massLabel                       matlab.ui.control.Label
        latch_mass                      matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        latch_coeff_fric                matlab.ui.control.NumericEditField
        radiusLabel                     matlab.ui.control.Label
        latch_radius                    matlab.ui.control.NumericEditField
        v_0Label                        matlab.ui.control.Label
        latch_v_0                       matlab.ui.control.NumericEditField
        minlatchingdistLabel            matlab.ui.control.Label
        min_latching_dist               matlab.ui.control.NumericEditField
        maxlatchingdistLabel            matlab.ui.control.Label
        max_latching_dist               matlab.ui.control.NumericEditField
        runwaylengthLabel               matlab.ui.control.Label
        runway_length                   matlab.ui.control.NumericEditField
        load                            matlab.ui.container.TabGroup
        load_mass                       matlab.ui.container.Tab
        massEditFieldLabel              matlab.ui.control.Label
        load_mass_mass                  matlab.ui.control.NumericEditField
        massofleverarmLabel             matlab.ui.control.Label
        load_m_rod                      matlab.ui.control.NumericEditField
        EMALabel                        matlab.ui.control.Label
        load_EMA                        matlab.ui.control.NumericEditField
        LoadingMotorLabel               matlab.ui.control.Label
        unlatching_motor                matlab.ui.container.TabGroup
        um_linear_motor                 matlab.ui.container.Tab
        FmaxLabel_5                     matlab.ui.control.Label
        um_linear_motor_Fmax            matlab.ui.control.NumericEditField
        VmaxLabel_3                     matlab.ui.control.Label
        um_linear_motor_Vmax            matlab.ui.control.NumericEditField
        rangeofmotionLabel_2            matlab.ui.control.Label
        um_linear_motor_range_of_motion  matlab.ui.control.NumericEditField
        voltagefracLabel_2              matlab.ui.control.Label
        um_linear_motor_voltage_frac    matlab.ui.control.NumericEditField
        um_hill_muscle_motor            matlab.ui.container.Tab
        FmaxLabel_6                     matlab.ui.control.Label
        um_hill_motor_Fmax              matlab.ui.control.NumericEditField
        VmaxLabel_4                     matlab.ui.control.Label
        um_hill_motor_Vmax              matlab.ui.control.NumericEditField
        musclelengthLabel_2             matlab.ui.control.Label
        um_hill_motor_muscle_length     matlab.ui.control.NumericEditField
        rateofactivationLabel_2         matlab.ui.control.Label
        um_hill_motor_rate_of_activation  matlab.ui.control.NumericEditField
        initiallengthLabel_2            matlab.ui.control.Label
        um_hill_motor_L_i               matlab.ui.control.NumericEditField
        a_LLabel_2                      matlab.ui.control.Label
        um_hill_motor_a_L               matlab.ui.control.NumericEditField
        b_LLabel_2                      matlab.ui.control.Label
        um_hill_motor_b_L               matlab.ui.control.NumericEditField
        sLabel_2                        matlab.ui.control.Label
        um_hill_motor_s                 matlab.ui.control.NumericEditField
        UnlatchingMotorLabel            matlab.ui.control.Label
        GraphingCornerLabel             matlab.ui.control.Label
        graphing_corner                 matlab.ui.container.TabGroup
        graphing_corner_kinematics      matlab.ui.container.Tab
        KinematicsOutputOptionsLabel    matlab.ui.control.Label
        forcedisp                       matlab.ui.control.CheckBox
        latchkinematicsCheckBox         matlab.ui.control.CheckBox
        loadkinematicsCheckBox          matlab.ui.control.CheckBox
        LoadingphaseLabel               matlab.ui.control.Label
        UnlatchingandlaunchingphaseLabel  matlab.ui.control.Label
        graphing_corner_one_D           matlab.ui.container.Tab
        OD_a_maxCheckBox                matlab.ui.control.CheckBox
        xaxisLabel_2                    matlab.ui.control.Label
        OD_y_maxCheckBox                matlab.ui.control.CheckBox
        OD_y_unlatchCheckBox            matlab.ui.control.CheckBox
        OD_t_LCheckBox                  matlab.ui.control.CheckBox
        OD_v_toCheckBox                 matlab.ui.control.CheckBox
        OD_P_maxCheckBox                matlab.ui.control.CheckBox
        OD_t_toCheckBox                 matlab.ui.control.CheckBox
        OD_KE_maxCheckBox               matlab.ui.control.CheckBox
        OD_IV1DropDown                  matlab.ui.control.DropDown
        yaxisOutputOptionsLabel         matlab.ui.control.Label
        pixelsofresolutionLabel_2       matlab.ui.control.Label
        OD_n                            matlab.ui.control.NumericEditField
        xminEditFieldLabel_2            matlab.ui.control.Label
        OD_xmin                         matlab.ui.control.NumericEditField
        xmaxEditFieldLabel_2            matlab.ui.control.Label
        OD_xmax                         matlab.ui.control.NumericEditField
        OD_x_log_space                  matlab.ui.control.Switch
        ComparetoDirectActuationCheckBox  matlab.ui.control.CheckBox
        graphing_corner_heatmap         matlab.ui.container.Tab
        a_maxCheckBox                   matlab.ui.control.CheckBox
        xaxisLabel                      matlab.ui.control.Label
        yaxisLabel                      matlab.ui.control.Label
        y_maxCheckBox                   matlab.ui.control.CheckBox
        y_unlatchCheckBox               matlab.ui.control.CheckBox
        t_LCheckBox                     matlab.ui.control.CheckBox
        v_toCheckBox                    matlab.ui.control.CheckBox
        P_maxCheckBox                   matlab.ui.control.CheckBox
        t_toCheckBox                    matlab.ui.control.CheckBox
        KE_maxCheckBox                  matlab.ui.control.CheckBox
        IV1DropDown                     matlab.ui.control.DropDown
        IV2DropDown                     matlab.ui.control.DropDown
        xminEditFieldLabel              matlab.ui.control.Label
        xmin                            matlab.ui.control.NumericEditField
        xmaxEditFieldLabel              matlab.ui.control.Label
        xmax                            matlab.ui.control.NumericEditField
        yminLabel                       matlab.ui.control.Label
        ymin                            matlab.ui.control.NumericEditField
        ymaxLabel                       matlab.ui.control.Label
        ymax                            matlab.ui.control.NumericEditField
        HeatmapOutputOptionsLabel       matlab.ui.control.Label
        pixelsofresolutionLabel         matlab.ui.control.Label
        n                               matlab.ui.control.NumericEditField
        x_log_space                     matlab.ui.control.Switch
        y_log_space                     matlab.ui.control.Switch
        MakeLaMSAZonePlotCheckBox       matlab.ui.control.CheckBox
        graphing_corner_sensitivity     matlab.ui.container.Tab
        VariablesListBoxLabel           matlab.ui.control.Label
        sensitivity_vars                matlab.ui.control.ListBox
        pressCTRLtoselectmultipleLabel  matlab.ui.control.Label
        MetricButtonGroup               matlab.ui.container.ButtonGroup
        ymaxButton                      matlab.ui.control.RadioButton
        yunlatchButton                  matlab.ui.control.RadioButton
        tLButton                        matlab.ui.control.RadioButton
        vtoButton                       matlab.ui.control.RadioButton
        PmaxButton                      matlab.ui.control.RadioButton
        ttoButton                       matlab.ui.control.RadioButton
        KEmaxButton                     matlab.ui.control.RadioButton
        go                              matlab.ui.control.Button
        ShowModelSchematicButton        matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        savesolutionCheckBox            matlab.ui.control.CheckBox
    end

    properties (Access = private)
        load_vars;
        latch_vars;
        spring_vars;
        lm_vars;
        um_vars;
        dd_vars;
        dd_vars_labels;
        axis_labels_dict;
        dropdown_items_dict;
        dropdown_items_opposite_dict;
    end
    
    methods (Access = private)
        function update_dd_vars(app)
            app.dd_vars = [app.load_vars app.latch_vars app.spring_vars app.lm_vars app.um_vars];
            for i=1:length(app.dd_vars)
                app.dd_vars_labels{i} = app.dropdown_items_dict(app.dd_vars{i});
            end
            app.IV1DropDown.Items = app.dd_vars_labels;
            app.IV2DropDown.Items = app.dd_vars_labels;
            app.OD_IV1DropDown.Items = app.dd_vars_labels;
            app.sensitivity_vars.Items = app.dd_vars_labels;
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function heatmap(app)
            
            if strcmp(app.x_log_space.Value,'log')
                if ((app.xmin.Value <= 0) ||(app.xmax.Value <= 0))
                    warndlg("One or both of the values you've entered for the xmin/xmax " + ...
                        "looping range is non-positive, " + ...
                        "AND you have chosen a logspace x-axis. " + newline +...
                        "Either change to linear spacing or use a positive bound.", 'Warning')
                    return
                end
            end
            
            if strcmp(app.y_log_space.Value,'log')
                if ((app.ymin.Value <= 0) || (app.ymax.Value <= 0))
                    warndlg("One or both of the values you've entered for the ymin/ymax " + ...
                        "looping range is non-positive,..." + ...
                        "AND you have chosen a logspace y-axis. " + newline +...
                        "Either change to linear spacing or use a positive bound.", 'Warning')
                    return
                end
            end
            
            if ~(app.y_maxCheckBox.Value || app.y_unlatchCheckBox.Value || app.t_LCheckBox.Value || ...
                    app.v_toCheckBox.Value || app.P_maxCheckBox.Value || app.t_toCheckBox.Value || app.KE_maxCheckBox.Value)
                warndlg('You must select at least one output option', 'Warning');
                return
            end
            
            % determines resolution of heatplots
            N=app.n.Value;
            
            % output directory initialization
            if app.savesolutionCheckBox.Value
                output_directory = create_output_directory();
            end
            
            %% plot parameters
            
            looping_param_x = app.dropdown_items_opposite_dict(app.IV1DropDown.Value);
            looping_param_y = app.dropdown_items_opposite_dict(app.IV2DropDown.Value);
            eval(['looping_param_x_value = app.' app.dropdown_items_opposite_dict(app.IV1DropDown.Value) '.Value;']);
            eval(['looping_param_y_value = app.' app.dropdown_items_opposite_dict(app.IV2DropDown.Value) '.Value;']);
            
            eval(['looping_param_x_limits = app.' app.dropdown_items_opposite_dict(app.IV1DropDown.Value) '.Limits;']);
            eval(['looping_param_y_limits = app.' app.dropdown_items_opposite_dict(app.IV1DropDown.Value) '.Limits;']);
            
            if ((app.xmin.Value < looping_param_x_limits(1)) | ...
                (app.xmin.Value > looping_param_x_limits(2)) | ...
                (app.xmax.Value < looping_param_x_limits(1)) | ...
                (app.xmax.Value > looping_param_x_limits(2)))     
                warndlg("You have chosen an xmin or xmax value that is out of the bounds for the looping quantity you have selected." + newline + ...
                    "i.e. you have chosen to loop through negative masses, or negative coefficients of friction, or something similarly bad.", ...
                    'Warning')
                return
            end
            if ((app.ymin.Value < looping_param_y_limits(1)) | ...
                (app.ymin.Value > looping_param_y_limits(2)) | ...
                (app.ymax.Value < looping_param_y_limits(1)) | ...
                (app.ymax.Value > looping_param_y_limits(2)))     
                warndlg("You have chosen an ymin or ymax value that is out of the bounds for the looping quantity you have selected." + newline + ...
                    "i.e. you have chosen to loop through negative masses, or negative coefficients of friction, or something similarly bad.", ...
                    'Warning')
                return
            end
            
            % initializing waitbar
            f = waitbar(0,'Please wait...');
            load_bar_value = 1/N;
            load_bar_increment = 1/N;
            
            % setting x axis on the plot
            xname = app.dropdown_items_opposite_dict(app.IV1DropDown.Value);
            if strcmp(app.x_log_space.Value,'log')
                xrange = [log10(app.xmin.Value) log10(app.xmax.Value)];
                looping_value_x = logspace(xrange(1),xrange(2),N);
            else
                xrange = [app.xmin.Value app.xmax.Value];
                looping_value_x = linspace(xrange(1),xrange(2),N);
            end
            
            %setting y axis value on plot
            yname = app.dropdown_items_opposite_dict(app.IV2DropDown.Value);
            if strcmp(app.y_log_space.Value,'log')
                yrange = [log10(app.ymin.Value) log10(app.ymax.Value)];
                looping_value_y = logspace(yrange(1),yrange(2),N);
            else
                yrange = [app.ymin.Value app.ymax.Value];
                looping_value_y = linspace(yrange(1),yrange(2),N);
            end
            
            % add things to metrics
            metrics = {};
            metrics_names = {};
            metrics_labels = {};
            if app.y_maxCheckBox.Value
                metrics{end+1} = 'ymax';
                metrics_names{end+1} = 'ymax';
                metrics_labels{end+1} = '$y_{\textrm{max}}$';
            end
            if app.y_unlatchCheckBox.Value
                metrics{end+1} = 'yunlatch';
                metrics_names{end+1} = 'yunlatch';
                metrics_labels{end+1} = '$y_{\textrm{unlatch}}$';
            end
            if app.t_LCheckBox.Value
                metrics{end+1} = 'tL';
                metrics_names{end+1} = 'tL';
                metrics_labels{end+1} = '$t_L$';
            end
            if app.v_toCheckBox.Value
                metrics{end+1} = 'vto';
                metrics_names{end+1} = 'vto';
                metrics_labels{end+1} = '$v_{\textrm{to}}$';
            end
            if app.P_maxCheckBox.Value
                metrics{end+1} = 'Pmax';
                metrics_names{end+1} = 'Pmax';
                metrics_labels{end+1} = '$P_{\textrm{max}}$';
            end
            if app.t_toCheckBox.Value
                metrics{end+1} = 'tto';
                metrics_names{end+1} = 'tto';
                metrics_labels{end+1} = '$t_{\textrm{to}}$';
            end
            if app.a_maxCheckBox.Value
                metrics{end+1} = 'amax';
                metrics_names{end+1} = 'amax';
                metrics_labels{end+1} = '$a_{\textrm{max}}$';
            end
            if app.KE_maxCheckBox.Value
                metrics{end+1} = 'KEmax';
                metrics_names{end+1} = 'KEmax';
                metrics_labels{end+1} = '$KE_{\textrm{max}}$';
            end
%             if app.KERatioCheckBox.Value
%                 metrics{end+1} = 'KE_Ratio';
%                 metrics_names{end+1} = 'KE_Ratio';
%                 metrics_labels{end+1} = 'KE Ratio';
%             end
%             if app.unlatchingmotorworkdoneCheckBox.Value
%                 metrics{end+1} = 'unlatching_motor_work_done';
%                 metrics_names{end+1} = 'unlatching_motor_work_done';
%                 metrics_labels{end+1} = 'unlatching motor work done';
%             end
            
            
            if isempty(metrics)
                warndlg('You must pick at least one output option','Error');
                return
            end
            
            
            metrics_dict = containers.Map(metrics_names,metrics_labels);
            
            
%             looping_param_x = app.dropdown_items_opposite_dict(app.IV1DropDown.Value);
%             looping_param_y = app.dropdown_items_opposite_dict(app.IV2DropDown.Value);
%             eval(['looping_param_x_value = app.' app.dropdown_items_opposite_dict(app.IV1DropDown.Value) '.Value;']);
%             eval(['looping_param_y_value = app.' app.dropdown_items_opposite_dict(app.IV2DropDown.Value) '.Value;']);
            
            % ensures that both independent variables that we are varying over are not the same
            if (strcmp(looping_param_y,looping_param_x))
                errordlg('The two independent variables must be different. Please change them and retry.','Error');
                return
            end
            
            for ii=1:length(metrics)
                outval{ii}=zeros(N);
            end
            for i=1:N %iterate over y-axis-variable of plot
                for j=1:N %iterate over x-axis-variable of plot       
                    
                    eval(['app.' looping_param_x '.Value = ' num2str(looping_value_x(j)) ';'])
                    eval(['app.' looping_param_y '.Value = ' num2str(looping_value_y(i)) ';'])
                    %% initializing LaMSA component structs
                    
                    % load mass struct initialization
                    % coupling for mass used in the paper: app.load_mass_mass.Value = (app.lm_hill_motor_Fmax.Value/86.2)^1.5;
                    load = load_mass(app.load_mass_mass.Value,app.load_m_rod.Value,app.load_EMA.Value);
                    
                    % latch struct initialization
                    latch = rounded_latch(app.latch_radius.Value,app.latch_mass.Value,app.latch_coeff_fric.Value, app.latch_v_0.Value, app.min_latching_dist.Value, app.max_latching_dist.Value, app.runway_length.Value);
                    
                    % spring struct initialization
                    if (app.spring.SelectedTab == app.linear_spring)
                        spring = linear_spring(app.linear_spring_k.Value,app.linear_spring_mass.Value,app.linear_spring_Fmax.Value);
                    elseif (app.spring.SelectedTab == app.exponential_spring)
                        spring = exponential_spring(app.exp_spring_k.Value,app.exp_spring_char_len.Value,app.exp_spring_mass.Value,app.exp_spring_Fmax.Value);
                    elseif (app.spring.SelectedTab == app.linear_elastic_extensional_spring)
                        spring = linear_elastic_extensional_spring(app.lee_spring_E.Value,app.lee_spring_A.Value,app.lee_spring_L.Value,app.lee_spring_rho.Value,app.lee_spring_sigma_f.Value);
                    end
                    
                    
                    % loading motor struct initialization
                    if (app.loading_motor.SelectedTab == app.lm_linear_motor)
                        loading_motor = linear_motor(app.lm_linear_motor_Fmax.Value,app.lm_linear_motor_Vmax.Value,app.lm_linear_motor_range_of_motion.Value,app.lm_linear_motor_voltage_frac.Value);
                    elseif (app.loading_motor.SelectedTab == app.lm_hill_muscle_motor)
                        loading_motor = hill_muscle_motor(app.lm_hill_motor_muscle_length.Value,app.lm_hill_motor_Fmax.Value,app.lm_hill_motor_Vmax.Value,app.lm_hill_motor_rate_of_activation.Value,app.lm_hill_motor_L_i.Value,app.lm_hill_motor_a_L.Value,app.lm_hill_motor_b_L.Value,app.lm_hill_motor_s.Value);
                    end
                    
                    % unlatching motor struct initialization
                    if (app.unlatching_motor.SelectedTab == app.um_linear_motor)
                        unlatching_motor = linear_motor(app.um_linear_motor_Fmax.Value,app.um_linear_motor_Vmax.Value,app.um_linear_motor_range_of_motion.Value,app.um_linear_motor_voltage_frac.Value);
                    elseif (app.unlatching_motor.SelectedTab == app.um_hill_muscle_motor)
                        unlatching_motor = hill_muscle_motor(app.um_hill_motor_muscle_length.Value,app.um_hill_motor_Fmax.Value,app.um_hill_motor_Vmax.Value,app.um_hill_motor_rate_of_activation.Value,app.um_hill_motor_L_i.Value,app.um_hill_motor_a_L.Value,app.um_hill_motor_b_L.Value,app.um_hill_motor_s.Value);
                    end
                    
                    % calling solve model
                    if app.savesolutionCheckBox.Value
                        [sol,transition_times] = solve_model(loading_motor,unlatching_motor,load,latch,spring,output_directory);
                    else
                        [sol,transition_times] = solve_model(loading_motor,unlatching_motor,load,latch,spring);
                    end
                    
                    % warning messages
                    [warnMsg, warnId] = lastwarn;
                    if (strcmp(warnMsg,'Loading failed. Does not fall within latching distance conditions.'))
                        if ~exist('loading_failed','var')
                            loading_failed = warndlg('Loading failed. Does not fall within latching distance conditions.','Warning');
                        end
                    end
                    if (strcmp(warnMsg,'Latch gets stuck!'))
                        if ~exist('latch_stuck','var')
                            latch_stuck = warndlg('Latch gets stuck!','Warning');
                        end
                    end
                    if (strcmp(warnMsg,"The latch's initial velocity and acceleration are both zero."))
                        if ~exist('latch_vi_a_zero','var')
                            latch_vi_a_zero = warndlg("The latch's initial velocity and acceleration are both zero.",'Warning');
                        end
                    end
                   

                    met_dict=get_metrics(sol,transition_times,load,metrics);
                    for ii=1:length(metrics)
                        outval{ii}(i,j)=met_dict(metrics{ii});
                    end
                    
                    % Run direct actuation model if MakeLaMSAZonePlotCheckBox
                    % is selected
                    if (app.MakeLaMSAZonePlotCheckBox.Value)
                        [direct_sol, direct_transition_times] = solve_direct_actuation(loading_motor,load);
                        direct_met_dict=get_metrics(direct_sol,direct_transition_times,load,metrics);
                        for ii=1:length(metrics)
                            direct_outval{ii}(i,j)=direct_met_dict(metrics{ii});
                        end
                    end
                    
                end
                disp(['row ' num2str(i) ' of ' num2str(N)]);
                load_bar_value = load_bar_value + load_bar_increment;
                waitbar(load_bar_value,f,'Processing...');
            end
            
            close(f)
            
            eval(['app.' app.dropdown_items_opposite_dict(app.IV1DropDown.Value) '.Value = looping_param_x_value;']);
            eval(['app.' app.dropdown_items_opposite_dict(app.IV2DropDown.Value) '.Value = looping_param_y_value;']);
            
            % plot output
            fh = figure('Name','LaMSA Heatmaps');
            fh.WindowState = 'maximized';
            subplot_rows = floor(sqrt(length(metrics)));
            subplot_cols = ceil(length(metrics)/floor(sqrt(length(metrics))));
            for ii=1:length(metrics)
                subplot(subplot_rows,subplot_cols,ii);
                
                % use an imageHandle so that you can click on the heatplot 
                % and have a kinematics plot show up
                imageHandle = imagesc(xrange,yrange,outval{ii});
                set(gca,'YDir','normal');
                xlabel(app.axis_labels_dict(xname),'Interpreter', 'Latex');
                ylabel(app.axis_labels_dict(yname), 'Interpreter', 'Latex');
                
                c = colorbar;
                % make "better performance" a darker blue
                if (strcmp(metrics_names{ii},'tto')) 
                    colormap(gca,flipud(linspecer('blue'))); % for tto - smaller means better, so flip
                else
                    colormap(gca,linspecer('blue')); % otherwise, higher is better
                end
                c.Label.Interpreter = 'Latex';
                set(c,'TickLabelInterpreter','Latex');
                c.Label.String = metrics_dict(metrics_names{ii});
                
                
                % makes the x and y axes in log scale
                set(gca,'TickLabelInterpreter','Latex');
                
                xtick = get(gca,'XTick');
                ytick = get(gca,'YTick');
                xticklabel = get(gca,'XTickLabel');
                yticklabel = get(gca,'YTickLabel');
                if strcmp(app.x_log_space.Value,'log')
                    for i=1:length(xticks)
                        xticklabel{i} = strcat('$10^{',num2str(xtick(i)),'}$');
                    end
                end
                set(gca,'XTickLabel',xticklabel);
                set(gca,'XTick',xtick);
                if strcmp(app.y_log_space.Value,'log')
                    for j=1:length(yticks)
                        yticklabel{j} = strcat('$10^{',num2str(ytick(j)),'}$');
                    end
                end
                set(gca,'YTickLabel',yticklabel);
                set(gca,'YTick',ytick);
                set(imageHandle,'ButtonDownFcn',{@showKinematics, looping_param_x, looping_param_y, app});
                
            end
            
            % plot direct actuation output
            if (app.MakeLaMSAZonePlotCheckBox.Value)

                fh = figure('Name','Direct Actuation Heatmaps');
                fh.WindowState = 'maximized';
                subplot_rows = floor(sqrt(length(metrics)));
                subplot_cols = ceil(length(metrics)/floor(sqrt(length(metrics))));
                for ii=1:length(metrics)
                    subplot(subplot_rows,subplot_cols,ii);
                    
                    imagesc(xrange,yrange,direct_outval{ii});
                    set(gca,'YDir','normal');
                    xlabel(app.axis_labels_dict(xname),'Interpreter', 'Latex');
                    ylabel(app.axis_labels_dict(yname), 'Interpreter', 'Latex');
                    
                    c = colorbar;
                    % make "better performance" a darker red
                    if (strcmp(metrics_names{ii},'tto')) 
                        colormap(gca,flipud(linspecer('red'))); % for tto - smaller means better, so flip
                    else
                        colormap(gca,linspecer('red')); % otherwise, higher is better
                    end
                 
                    c.Label.Interpreter = 'Latex';
                    set(c,'TickLabelInterpreter','Latex');
                    c.Label.String = metrics_dict(metrics_names{ii});
                    
                    
                    % makes the x and y axes in log scale
                    set(gca,'TickLabelInterpreter','Latex');
                    
                    xtick = get(gca,'XTick');
                    ytick = get(gca,'YTick');
                    xticklabel = get(gca,'XTickLabel');
                    yticklabel = get(gca,'YTickLabel');
                    if strcmp(app.x_log_space.Value,'log')
                        for i=1:length(xticks)
                            xticklabel{i} = strcat('$10^{',num2str(xtick(i)),'}$');
                        end
                    end
                    set(gca,'XTickLabel',xticklabel);
                    set(gca,'XTick',xtick);
                    if strcmp(app.y_log_space.Value,'log')
                        for j=1:length(yticks)
                            yticklabel{j} = strcat('$10^{',num2str(ytick(j)),'}$');
                        end
                    end
                    set(gca,'YTickLabel',yticklabel);
                    set(gca,'YTick',ytick);
                end
                
                
                fh = figure('Name','LaMSA Zone Heatmaps');
                fh.WindowState = 'maximized';
                subplot_rows = floor(sqrt(length(metrics)));
                subplot_cols = ceil(length(metrics)/floor(sqrt(length(metrics))));
                for ii=1:length(metrics)
                    subplot(subplot_rows,subplot_cols,ii);
                    
                    % calculate LaMSA Zone ratio
                    if (strcmp(metrics_names{ii},'tto')) 
                        LaMSAval = direct_outval{ii}./outval{ii};
                    else
                        LaMSAval = outval{ii}./direct_outval{ii};
                    end
            
                    max_output = max(2,max(LaMSAval,[],'all'));
                    min_output = min(0.5,min(LaMSAval,[],'all'));
                    
                    % map output onto the range 0 to 2 for the colormap to
                    % have an even spread between LaMSA and non-LaMSA
                    scaledval = LaMSAval;
                    idx = find(scaledval>1);
                    scaledval(idx) = (scaledval(idx)-1)/(max_output-1) + 1;
                    idx = find(scaledval<1);
                    scaledval(idx) = 1 - (1-scaledval(idx))/(1-min_output);
                    
                    % dispay scaled value of LaMSA ratio
                    imagesc(xrange,yrange,scaledval);
                    set(gca,'YDir','normal');
                    xlabel(app.axis_labels_dict(xname),'Interpreter', 'Latex');
                    ylabel(app.axis_labels_dict(yname), 'Interpreter', 'Latex');
                    
                    c = colorbar;
                    % make a LaMSA Zone colormap going red-->white-->blue
                    rval = [.42 0.03 0.13]; % starting red value
                    wval = [ 1 1 1];
                    bval = [0.03 0.18 .42]; % ending blue value
                    colmap_res = 100; % number of colors in each range of the colormap
                    bcolmap = [linspace(rval(1),wval(1),colmap_res)',linspace(rval(2),wval(2),colmap_res)',linspace(rval(3),wval(3),colmap_res)'];
                    rcolmap = [linspace(wval(1),bval(1),colmap_res)',linspace(wval(2),bval(2),colmap_res)',linspace(wval(3),bval(3),colmap_res)'];
                    colmap = [bcolmap;rcolmap];
                    colormap(gca,colmap);
                    caxis([0,2]);
                    set(c,'YTick',[0,1,2]);
                    set(c,'YTickLabel',{strcat('1/',num2str(1/min_output,'%.0f')),'1',num2str(max_output,'%.0f')});
                    c.Label.Interpreter = 'Latex';
                    set(c,'TickLabelInterpreter','Latex');
                    c.Label.String = strcat("LaMSA Zone Ratio for ", metrics_dict(metrics_names{ii}));
                    
                    
                    % makes the x and y axes in log scale
                    set(gca,'TickLabelInterpreter','Latex');
                    
                    xtick = get(gca,'XTick');
                    ytick = get(gca,'YTick');
                    xticklabel = get(gca,'XTickLabel');
                    yticklabel = get(gca,'YTickLabel');
                    if strcmp(app.x_log_space.Value,'log')
                        for i=1:length(xticks)
                            xticklabel{i} = strcat('$10^{',num2str(xtick(i)),'}$');
                        end
                    end
                    set(gca,'XTickLabel',xticklabel);
                    set(gca,'XTick',xtick);
                    if strcmp(app.y_log_space.Value,'log')
                        for j=1:length(yticks)
                            yticklabel{j} = strcat('$10^{',num2str(ytick(j)),'}$');
                        end
                    end
                    set(gca,'YTickLabel',yticklabel);
                    set(gca,'YTick',ytick);
                end
            end
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function one_D_plot(app)
            if ~(app.OD_y_maxCheckBox.Value || app.OD_y_unlatchCheckBox.Value || app.OD_t_LCheckBox.Value || ...
                    app.OD_v_toCheckBox.Value || app.OD_P_maxCheckBox.Value || app.OD_t_toCheckBox.Value || app.OD_KE_maxCheckBox.Value)
                warndlg('You must select at least one output option', 'Warning');
                return
            end
            if strcmp(app.OD_x_log_space.Value,'log')
                if ((app.OD_xmin.Value <= 0) |(app.OD_xmax.Value <= 0))
                    warndlg("One or both of the values you've entered for the xmin/xmax " + ...
                        "looping range is non-positive, " + ...
                        "AND you have chosen a logspace x-axis. " + newline +...
                        "Either change to linear spacing or use a positive bound.", 'Warning')
                    return
                end
            end
            
            % determines resolution of heatplots
            N=app.OD_n.Value; 
            
            % output directory initialization
            if app.savesolutionCheckBox.Value
                output_directory = create_output_directory();
            end
            
            %% plot parameters
            looping_param_x = app.dropdown_items_opposite_dict(app.OD_IV1DropDown.Value);
            eval(['looping_param_x_value = app.' app.dropdown_items_opposite_dict(app.OD_IV1DropDown.Value) '.Value;']);
            eval(['looping_param_x_limits = app.' app.dropdown_items_opposite_dict(app.OD_IV1DropDown.Value) '.Limits;']);
            
            if ((app.OD_xmin.Value < looping_param_x_limits(1)) | ...
                (app.OD_xmin.Value > looping_param_x_limits(2)) | ...
                (app.OD_xmax.Value < looping_param_x_limits(1)) | ...
                (app.OD_xmax.Value > looping_param_x_limits(2)))     
                warndlg("You have chosen an xmin or xmax value that is out of the bounds for the looping quantity you have selected." + newline + ...
                    "i.e. you have chosen to loop through negative masses, or negative coefficients of friction, or something similarly bad.", ...
                    'Warning')
                return
            end
            
            % initializing waitbar
            f = waitbar(0,'Please wait...');
            load_bar_value = 1/N;
            load_bar_increment = 1/N;
            
            % setting x axis on the plot
            xname = app.dropdown_items_opposite_dict(app.OD_IV1DropDown.Value);
            if strcmp(app.OD_x_log_space.Value,'log')
                xrange = [log10(app.OD_xmin.Value) log10(app.OD_xmax.Value)];
                looping_value_x = logspace(xrange(1),xrange(2),N);
            else
                xrange = [app.OD_xmin.Value app.OD_xmax.Value];
                looping_value_x = linspace(xrange(1),xrange(2),N);
            end
            
            % add things to metrics
            metrics = {};
            
            if app.OD_y_maxCheckBox.Value
                metrics{end+1} = 'ymax';
            end
            if app.OD_y_unlatchCheckBox.Value
                metrics{end+1} = 'yunlatch';
            end
            if app.OD_t_LCheckBox.Value
                metrics{end+1} = 'tL';
            end
            if app.OD_v_toCheckBox.Value
                metrics{end+1} = 'vto';
            end
            if app.OD_P_maxCheckBox.Value
                metrics{end+1} = 'Pmax';
            end
            if app.OD_t_toCheckBox.Value
                metrics{end+1} = 'tto';
            end
            if app.OD_a_maxCheckBox.Value
                metrics{end+1} = 'amax';
            end
            if app.OD_KE_maxCheckBox.Value
                metrics{end+1} = 'KEmax';
            end

            if isempty(metrics)
                warndlg('You must pick at least one output option','Error');
                return
            end
            
            metrics_names = {'ymax','yunlatch','tL','vto','Pmax','tto','amax','KEmax', 'KE_Ratio', 'unlatching_motor_work_done'};
            metrics_labels = {'$y_{\textrm{max}}$','$y_{\textrm{unlatch}}$','$t_L$','$v_{\textrm{to}}$','$P_{\textrm{max}}$','$t_{\textrm{to}}$','$a_\textrm{max}$','$KE_{\textrm{max}}$', 'KE Ratio', 'unlatching motor work done'};
            metrics_dict = containers.Map(metrics_names,metrics_labels);
            
            for ii=1:length(metrics)
                outval{ii} = zeros(size(looping_value_x));
            end
            for i=1:N    
                eval(['app.' looping_param_x '.Value = ' num2str(looping_value_x(i)) ';']);
                
                %% initializing LaMSA component structs
        
                % load mass struct initialization
                % coupling for mass used in the paper: app.load_mass_mass.Value = (app.lm_hill_motor_Fmax.Value/86.2)^1.5;
                load = load_mass(app.load_mass_mass.Value,app.load_m_rod.Value,app.load_EMA.Value);
                
                % latch struct initialization
                latch = rounded_latch(app.latch_radius.Value,app.latch_mass.Value,app.latch_coeff_fric.Value, app.latch_v_0.Value, app.min_latching_dist.Value, app.max_latching_dist.Value, app.runway_length.Value);
                
                % spring struct initialization
                if (app.spring.SelectedTab == app.linear_spring)
                    spring = linear_spring(app.linear_spring_k.Value,app.linear_spring_mass.Value,app.linear_spring_Fmax.Value);
                elseif (app.spring.SelectedTab == app.exponential_spring)
                    spring = exponential_spring(app.exp_spring_k.Value,app.exp_spring_char_len.Value,app.exp_spring_mass.Value,app.exp_spring_Fmax.Value);
                elseif (app.spring.SelectedTab == app.linear_elastic_extensional_spring)
                    spring = linear_elastic_extensional_spring(app.lee_spring_E.Value,app.lee_spring_A.Value,app.lee_spring_L.Value,app.lee_spring_rho.Value,app.lee_spring_sigma_f.Value);
                end
                
                % loading motor struct initialization
                if (app.loading_motor.SelectedTab == app.lm_linear_motor)
                    loading_motor = linear_motor(app.lm_linear_motor_Fmax.Value,app.lm_linear_motor_Vmax.Value,app.lm_linear_motor_range_of_motion.Value,app.lm_linear_motor_voltage_frac.Value);
                elseif (app.loading_motor.SelectedTab == app.lm_hill_muscle_motor)
                    loading_motor = hill_muscle_motor(app.lm_hill_motor_muscle_length.Value,app.lm_hill_motor_Fmax.Value,app.lm_hill_motor_Vmax.Value,app.lm_hill_motor_rate_of_activation.Value,app.lm_hill_motor_L_i.Value,app.lm_hill_motor_a_L.Value,app.lm_hill_motor_b_L.Value,app.lm_hill_motor_s.Value);
                end
                
                % unlatching motor struct initialization
                if (app.unlatching_motor.SelectedTab == app.um_linear_motor)
                    unlatching_motor = linear_motor(app.um_linear_motor_Fmax.Value,app.um_linear_motor_Vmax.Value,app.um_linear_motor_range_of_motion.Value,app.um_linear_motor_voltage_frac.Value);
                elseif (app.unlatching_motor.SelectedTab == app.um_hill_muscle_motor)
                    unlatching_motor = hill_muscle_motor(app.um_hill_motor_muscle_length.Value,app.um_hill_motor_Fmax.Value,app.um_hill_motor_Vmax.Value,app.um_hill_motor_rate_of_activation.Value,app.um_hill_motor_L_i.Value,app.um_hill_motor_a_L.Value,app.um_hill_motor_b_L.Value,app.um_hill_motor_s.Value);
                end
                
                % calling solve model
                if app.savesolutionCheckBox.Value
                    [sol,transition_times] = solve_model(loading_motor,unlatching_motor,load,latch,spring,output_directory);
                else
                    [sol,transition_times] = solve_model(loading_motor,unlatching_motor,load,latch,spring);
                end
                
                % warning messages
                [warnMsg, warnId] = lastwarn;
                if (strcmp(warnMsg,'Loading failed. Does not fall within latching distance conditions.'))
                    if ~exist('loading_failed','var')
                        loading_failed = warndlg('Loading failed. Does not fall within latching distance conditions.','Warning');
                    end
                end
                if (strcmp(warnMsg,'Latch gets stuck!'))
                    if ~exist('latch_stuck','var')
                        latch_stuck = warndlg('Latch gets stuck!','Warning');
                    end
                end
                if (strcmp(warnMsg,"The latch's initial velocity and acceleration are both zero."))
                    if ~exist('latch_vi_a_zero','var')
                        latch_vi_a_zero = warndlg("The latch's initial velocity and acceleration are both zero.",'Warning');
                    end
                end


                met_dict=get_metrics(sol,transition_times,load,metrics);
                for ii=1:length(metrics)
                    outval{ii}(i)=met_dict(metrics{ii});
                end


                % Run direct actuation model if ComparetoDirectActuationCheckBox
                % is selected
                if (app.ComparetoDirectActuationCheckBox.Value)
                    [direct_sol, direct_transition_times] = solve_direct_actuation(loading_motor,load);
                    direct_met_dict=get_metrics(direct_sol,direct_transition_times,load,metrics);
                    for ii=1:length(metrics)
                        direct_outval{ii}(i)=direct_met_dict(metrics{ii});
                    end
                end
                
                disp(['point ' num2str(i) ' of ' num2str(N)]);
                load_bar_value = load_bar_value + load_bar_increment;
                waitbar(load_bar_value,f,'Processing...');
            end

            close(f);
            eval(['app.' app.dropdown_items_opposite_dict(app.OD_IV1DropDown.Value) '.Value = looping_param_x_value;']);
            
            % plot output
            fh = figure('Name','1D Plot');
            fh.WindowState = 'maximized';
            subplot_rows = floor(sqrt(length(metrics)));
            subplot_cols = ceil(length(metrics)/floor(sqrt(length(metrics))));
            for ii=1:length(metrics)
                subplot(subplot_rows,subplot_cols,ii);
                plot(looping_value_x,outval{ii},'-b'); 
                if (app.ComparetoDirectActuationCheckBox.Value)
                    hold on;
                    plot(looping_value_x,direct_outval{ii},'-r');
                    legend({'LaMSA','Direct Actuation'},'Location','Best');
                end
                xlabel(app.axis_labels_dict(xname),'Interpreter', 'Latex');
                ylabel(metrics_dict(metrics{ii}), 'Interpreter', 'Latex');
                ylim_range = max(outval{ii})-min(outval{ii});
                if (ylim_range == 0)
                    ylim_range = abs(max(outval{ii}));
                    if ~(ylim_range == 0)
                        ylim([min([0, min(outval{ii})-0.05*ylim_range]) max([0,max(outval{ii})+0.05*ylim_range]) ])
                    end
                else
                    ylim([min(outval{ii})-0.05*ylim_range max(outval{ii})+0.05*ylim_range])
                end
                set(gca,'TickLabelInterpreter','latex')
                % makes the x in log scale if needed
                if strcmp(app.OD_x_log_space.Value,'log')
                    set(gca,'XScale','log')
                end
               
            end
            
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function kinematics(app)
            if ~(app.loadkinematicsCheckBox.Value || app.latchkinematicsCheckBox.Value || app.forcedisp.Value)
                warndlg('You must select at least one kinematics option', 'Warning');
                return
            end
            
            f = waitbar(0,'Please wait...');
            load_bar_value = 1/4;
            load_bar_increment = 1/4;
            
            % initializing output directory
            if app.savesolutionCheckBox.Value
                output_directory = create_output_directory();
            end
            
            %% initializing LaMSA component structs
            
            % load mass struct initialization
            load = load_mass(app.load_mass_mass.Value,app.load_m_rod.Value,app.load_EMA.Value);
            
            % latch struct initialization
            latch = rounded_latch(app.latch_radius.Value,app.latch_mass.Value,app.latch_coeff_fric.Value, app.latch_v_0.Value, app.min_latching_dist.Value, app.max_latching_dist.Value, app.runway_length.Value);
            
            % spring struct initialization
            if (app.spring.SelectedTab == app.linear_spring)
                spring = linear_spring(app.linear_spring_k.Value,app.linear_spring_mass.Value,app.linear_spring_Fmax.Value);
            elseif (app.spring.SelectedTab == app.exponential_spring)
                spring = exponential_spring(app.exp_spring_k.Value,app.exp_spring_char_len.Value,app.exp_spring_mass.Value,app.exp_spring_Fmax.Value);
            elseif (app.spring.SelectedTab == app.linear_elastic_extensional_spring)
                spring = linear_elastic_extensional_spring(app.lee_spring_E.Value,app.lee_spring_A.Value,app.lee_spring_L.Value,app.lee_spring_rho.Value,app.lee_spring_sigma_f.Value);
            end
            
            load_bar_value = load_bar_value + 2*load_bar_increment;
            waitbar(load_bar_value,f,'Processing...');
            
            % loading motor struct initialization
            if (app.loading_motor.SelectedTab == app.lm_linear_motor)
                loading_motor = linear_motor(app.lm_linear_motor_Fmax.Value,app.lm_linear_motor_Vmax.Value,app.lm_linear_motor_range_of_motion.Value,app.lm_linear_motor_voltage_frac.Value);
            elseif (app.loading_motor.SelectedTab == app.lm_hill_muscle_motor)
                loading_motor = hill_muscle_motor(app.lm_hill_motor_muscle_length.Value,app.lm_hill_motor_Fmax.Value,app.lm_hill_motor_Vmax.Value,app.lm_hill_motor_rate_of_activation.Value,app.lm_hill_motor_L_i.Value,app.lm_hill_motor_a_L.Value,app.lm_hill_motor_b_L.Value,app.lm_hill_motor_s.Value);
            end
            
            load_bar_value = load_bar_value + load_bar_increment;
            waitbar(load_bar_value,f,'Processing...');
            
            % unlatching motor struct initialization
            if (app.unlatching_motor.SelectedTab == app.um_linear_motor)
                unlatching_motor = linear_motor(app.um_linear_motor_Fmax.Value,app.um_linear_motor_Vmax.Value,app.um_linear_motor_range_of_motion.Value,app.um_linear_motor_voltage_frac.Value);
            elseif (app.unlatching_motor.SelectedTab == app.um_hill_muscle_motor)
                unlatching_motor = hill_muscle_motor(app.um_hill_motor_muscle_length.Value,app.um_hill_motor_Fmax.Value,app.um_hill_motor_Vmax.Value,app.um_hill_motor_rate_of_activation.Value,app.um_hill_motor_L_i.Value,app.um_hill_motor_a_L.Value,app.um_hill_motor_b_L.Value,app.um_hill_motor_s.Value);
            end
            
            if app.savesolutionCheckBox.Value
                    [sol,transition_times] = solve_model(loading_motor,unlatching_motor,load,latch,spring,output_directory);
                else
                    [sol,transition_times] = solve_model(loading_motor,unlatching_motor,load,latch,spring);
            end
            
            columntitles=["Time", "y postition", "y velocity", "x position", "x velocity", "normal force on latch x", ...
                "normal force on load y", "frictional force on latch x", ...
                "frictional force on load y", "spring force", ...
                "unlatching motor force into"];
            
            close(f)
            
            % latch kinematics
            if (app.latchkinematicsCheckBox.Value)    
                figure('Name','Latch Kinematics')
                for i = 4:5
                    subplot(1,3,i-3)
                    box on
                    set(gca,'TickLabelInterpreter','latex')
                    hold on
                    plot(sol(:,1),sol(:,i));
                    hold off
                    title(columntitles(i),"Interpreter","latex");
                    ylabel(columntitles(i),"Interpreter","latex");
                    xlabel(columntitles(1),"Interpreter","latex");
                end
                
                for i = [6 8 11]
                    subplot(1,3,3)
                    box on
                    set(gca,'TickLabelInterpreter','latex')
                    hold on
                    plot(sol(:,1),sol(:,i),"DisplayName",columntitles(i))
                end
                title("Latch Force Components","Interpreter","latex");
                ylabel("Force","Interpreter","latex");
                xlabel(columntitles(1),"Interpreter","latex");
                legend("show")
                hold off
            end
            
            % load kinematics
            if (app.loadkinematicsCheckBox.Value)
                figure('Name','Load Kinematics')
                for i = 2:3
                    subplot(1,3,i-1)
                    box on
                    set(gca,'TickLabelInterpreter','latex')
                    hold on
                    plot(sol(:,1),sol(:,i));
                    hold off
                    title(columntitles(i),"Interpreter","latex");
                    ylabel(columntitles(i),"Interpreter","latex");
                    xlabel(columntitles(1),"Interpreter","latex");
                end
                
                for i = [7 9 10]
                    subplot(1,3,3)
                    box on
                    set(gca,'TickLabelInterpreter','latex')
                    hold on
                    plot(sol(:,1),sol(:,i),"DisplayName",columntitles(i))
                end
                title("Load Force Components","Interpreter","latex");
                ylabel("Force","Interpreter","latex");
                xlabel(columntitles(1),"Interpreter","latex");
                legend("show")
                hold off
            end    
            
            % force displacement curves for motor and spring
            if (app.forcedisp.Value)    
                figure('Name','Motor and Spring Force Displacement Curves')
                if (loading_motor.range == Inf)
                    y_max = 5*sol(1,2);
                else
                    y_max = -loading_motor.range;
                end
                y_range = linspace(0,y_max,100);
                motor_force_array = [];
                spring_force_array = [];
                for i = 1:length(y_range)
                    motor_force_array(end+1) = loading_motor.Force(Inf,[-y_range(i),0]);
                    spring_force_array(end+1) = spring.Force(Inf,[y_range(i),0]);
                end
                force_arrays = {spring_force_array motor_force_array};
                for i = 1:2
                    box on
                    set(gca,'TickLabelInterpreter','latex')
                    hold on
                    plot(abs(y_range),force_arrays{i});
                end
                plot([-sol(1,2),-sol(1,2)],[0,spring.Force(Inf,[sol(1,2),0])],"--k")
                plot(-sol(1,2),spring.Force(Inf,[sol(1,2),0]),'ok')
                title('Force Displacement Curve',"Interpreter","latex");
                ylabel('Force',"Interpreter","latex");
                xlabel('Displacement',"Interpreter","latex");
                legend('spring','motor','loaded displacement')
            end
            
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function sensitivity(app)
            f = waitbar(1/4,'Please wait...');
            load_bar_value = 1/4;
            load_bar_increment = 1/4;
            
            labels = strings([1 length(app.sensitivity_vars.Value)]);
            x0 = zeros([1 length(labels)]);
            for i=1:length(labels)
                labels(i) = app.sensitivity_vars.Value(i);
                x0(i) = eval(['app.' app.dropdown_items_opposite_dict(labels(i)) '.Value;']);
            end
            load_str = "mass = @(x0)load_mass(app.load_mass_mass.Value,app.load_m_rod.Value,app.load_EMA.Value);";
            
            latch_str = "latch = @(x0)rounded_latch(app.latch_radius.Value,app.latch_mass.Value,app.latch_coeff_fric.Value, app.latch_v_0.Value, app.min_latching_dist.Value, app.max_latching_dist.Value, app.runway_length.Value);";
            
            if (app.spring.SelectedTab == app.linear_spring)
                spring_str = "spring_fun = @(x0)linear_spring(app.linear_spring_k.Value,app.linear_spring_mass.Value,app.linear_spring_Fmax.Value);";
            elseif (app.spring.SelectedTab == app.exponential_spring)
                spring_str = "spring_fun = @(x0)exponential_spring(app.exp_spring_k.Value,app.exp_spring_char_len.Value,app.exp_spring_mass.Value,app.exp_spring_Fmax.Value);";
            elseif (app.spring.SelectedTab == app.linear_elastic_extensional_spring)
                spring_str = "spring_fun = @(x0)linear_elastic_extensional_spring(app.lee_spring_E.Value,app.lee_spring_A.Value,app.lee_spring_L.Value,app.lee_spring_rho.Value,app.lee_spring_sigma_f.Value);";
            end

            if (app.loading_motor.SelectedTab == app.lm_linear_motor)
                loading_motor_str = "loading_motor = @(x0)linear_motor(app.lm_linear_motor_Fmax.Value,app.lm_linear_motor_Vmax.Value,app.lm_linear_motor_range_of_motion.Value,app.lm_linear_motor_voltage_frac.Value);";
            elseif (app.loading_motor.SelectedTab == app.lm_hill_muscle_motor)
                loading_motor_str = "loading_motor = @(x0)hill_muscle_motor(app.lm_hill_motor_muscle_length.Value,app.lm_hill_motor_Fmax.Value,app.lm_hill_motor_Vmax.Value,app.lm_hill_motor_rate_of_activation.Value,app.lm_hill_motor_L_i.Value,app.lm_hill_motor_a_L.Value,app.lm_hill_motor_b_L.Value,app.lm_hill_motor_s.Value);";
            end

            if (app.unlatching_motor.SelectedTab == app.um_linear_motor)
                unlatching_motor_str = "unlatching_motor = @(x0)linear_motor(app.um_linear_motor_Fmax.Value,app.um_linear_motor_Vmax.Value,app.um_linear_motor_range_of_motion.Value,app.um_linear_motor_voltage_frac.Value);";
            elseif (app.unlatching_motor.SelectedTab == app.um_hill_muscle_motor)
                unlatching_motor_str = "unlatchin_motor = @(x0)hill_muscle_motor(app.um_hill_motor_muscle_length.Value,app.um_hill_motor_Fmax.Value,app.um_hill_motor_Vmax.Value,app.um_hill_motor_rate_of_activation.Value,app.um_hill_motor_L_i.Value,app.um_hill_motor_a_L.Value,app.um_hill_motor_b_L.Value,app.um_hill_motor_s.Value);";
            end
            
            load_bar_value = load_bar_value + load_bar_increment;
            waitbar(load_bar_value,f,'Processing...');
            
            for i=1:length(labels)
                load_str = strrep(load_str,strcat("app.",app.dropdown_items_opposite_dict(labels(i)),".Value"),strcat("x0(",num2str(i),")"));
                latch_str = strrep(latch_str,strcat("app.",app.dropdown_items_opposite_dict(labels(i)),".Value"),strcat("x0(",num2str(i),")"));
                spring_str = strrep(spring_str,strcat("app.",app.dropdown_items_opposite_dict(labels(i)),".Value"),strcat("x0(",num2str(i),")"));
                loading_motor_str = strrep(loading_motor_str,strcat("app.",app.dropdown_items_opposite_dict(labels(i)),".Value"),strcat("x0(",num2str(i),")"));
                unlatching_motor_str = strrep(unlatching_motor_str,strcat("app.",app.dropdown_items_opposite_dict(labels(i)),".Value"),strcat("x0(",num2str(i),")"));
            end
            eval(load_str);
            eval(latch_str);
            eval(spring_str);
            eval(loading_motor_str);
            eval(unlatching_motor_str);
            
            load_bar_value = load_bar_value + load_bar_increment;
            waitbar(load_bar_value,f,'Processing...');
            
            [combos,var_list] = sensitivity_analysis(loading_motor,unlatching_motor,mass,latch,spring_fun,x0,app.MetricButtonGroup.SelectedObject.Text,labels);
            commandwindow;
            disp(' ');
            disp('----------------------');
            disp('Sensitivity Analysis');
            disp('----------------------');
            disp(' ');
            column_labels = strings([1, size(combos,2)]);
            for i=1:length(column_labels)
                column_labels(i) = "combo #"+num2str(i);
            end
            combo_table = array2table(combos,'RowNames',labels,'VariableNames',column_labels);
            disp('Most Sensitive Combinations of Parameters')
            disp(combo_table);
            disp('Most Sensitive Parameter Axes')
            for i=1:length(var_list)
                disp(var_list(i));
            end
            close(f)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            f = waitbar(0,'Initializing...');
            waitbar_increments = 5;
            waitbar_counter = 1;
            drawnow;
            app.UIFigure.WindowState = 'maximized';
            
            waitbar(waitbar_counter/waitbar_increments,f,'Finding components-library...');
            waitbar_counter = waitbar_counter + 1;
            % makes comparing changes in the app in github easier
            pathname = char(which("plot_app.mlapp"));
            lastIndex = strlength(pathname);
            nameLength = strlength("plot_app.mlapp");
            correctIndex = lastIndex-nameLength;
            pathname = pathname(1:correctIndex);
            pathname = pathname +"/..";
            addpath(genpath(fullfile(pathname)));
            mlapp2classdef("plot_app.mlapp");
            
            waitbar(waitbar_counter/waitbar_increments,f,'Setting up graphing options...');
            waitbar_counter = waitbar_counter + 1;
            
            varnames = {'load_mass_mass','load_m_rod','load_EMA',...
                'latch_mass','latch_coeff_fric','latch_radius','latch_v_0','min_latching_dist','max_latching_dist','runway_length'...
                'linear_spring_k','linear_spring_mass','linear_spring_Fmax',...
                'exp_spring_k','exp_spring_char_len','exp_spring_Fmax','exp_spring_mass',...
                'lee_spring_E','lee_spring_A','lee_spring_L','lee_spring_rho','lee_spring_sigma_f',...
                'lm_linear_motor_Fmax','lm_linear_motor_Vmax','lm_linear_motor_range_of_motion','lm_linear_motor_voltage_frac'...
                'lm_hill_motor_Fmax','lm_hill_motor_Vmax','lm_hill_motor_muscle_length','lm_hill_motor_rate_of_activation','lm_hill_motor_L_i','lm_hill_motor_a_L','lm_hill_motor_b_L','lm_hill_motor_s',...
                'um_linear_motor_Fmax','um_linear_motor_Vmax','um_linear_motor_range_of_motion','um_linear_motor_voltage_frac'...
                'um_hill_motor_Fmax','um_hill_motor_Vmax','um_hill_motor_muscle_length','um_hill_motor_rate_of_activation','um_hill_motor_L_i','um_hill_motor_a_L','um_hill_motor_b_L','um_hill_motor_s'};
            latexlabels = {'load mass','lever arm mass','EMA'...
                'latch mass','latch $\mu$','latch radius','latch $v_0$','min latching distance','max latching distance','runway length'...
                'linear spring k','linear spring mass','linear spring Fmax',...
                'exponential spring $k_0$','exponential spring characteristic length','exponential spring Fmax','exponential spring mass',...
                'spring E','spring A','spring L','spring $\rho$','spring $\sigma_f$',...
                'loading motor (linear) Fmax','loading motor (linear) motor Vmax','loading motor (linear) range of motion','loading motor (linear) voltage fraction'...
                'loading motor (Hill) Fmax','loading motor (Hill) Vmax','loading motor (Hill) muscle length','loading motor (Hill) rate of activation','loading motor (Hill) motor $L_i$','loading motor (Hill) $a_L$','loading motor (Hill) $b_L$','loading motor (Hill) s',...
                'unlatching motor (linear) Fmax','unlatching motor (linear) motor Vmax','unlatching motor (linear) range of motion','unlatching motor (linear) voltage fraction'...
                'unlatching motor (Hill) Fmax','unlatching motor (Hill) Vmax','unlatching motor (Hill) muscle length','unlatching motor (Hill) rate of activation','unlatching motor (Hill) motor $L_i$','unlatching motor (Hill) $a_L$','unlatching motor (Hill) $b_L$','unlatching motor (Hill) s'};
            nonlatexlabels = {'load mass','lever arm mass','EMA'...
                'latch mass','latch mu','latch radius','latch v_0','min latching distance','max latching distance','runway length'...
                'linear spring k','linear spring mass','linear spring Fmax',...
                'exponential spring k_0','exponential spring characteristic length','exponential spring Fmax','exponential spring mass',...
                'spring E','spring A','spring L','spring rho','spring sigma_f',...
                'loading motor (linear) Fmax','loading motor (linear) motor Vmax','loading motor (linear) range of motion', 'loading motor (linear) voltage fraction'...
                'loading motor (Hill) Fmax','loading motor (Hill) Vmax','loading motor (Hill) muscle length','loading motor (Hill) rate of activation','loading motor (Hill) motor L_i','loading motor (Hill) a_L','loading motor (Hill) b_L','loading motor (Hill) s',...
                'unlatching motor (linear) Fmax','unlatching motor (linear) motor Vmax','unlatching motor (linear) range of motion','unlatching motor (linear) voltage fraction'...
                'unlatching motor (Hill) Fmax','unlatching motor (Hill) Vmax','unlatching motor (Hill) muscle length','unlatching motor (Hill) rate of activation','unlatching motor (Hill) motor $L_i$','unlatching motor (Hill) $a_L$','unlatching motor (Hill) $b_L$','unlatching motor (Hill) s'};
            
            app.axis_labels_dict = containers.Map(varnames,latexlabels);
            app.dropdown_items_dict = containers.Map(varnames,nonlatexlabels);
            app.dropdown_items_opposite_dict = containers.Map(nonlatexlabels,varnames);

            app.sensitivity_vars.Multiselect = 'on';
            
            app.load_vars = {'load_mass_mass','load_m_rod','load_EMA'};
            app.latch_vars = {'latch_mass','latch_coeff_fric','latch_radius','latch_v_0','min_latching_dist','max_latching_dist','runway_length'};
            app.spring_vars = {'exp_spring_k','exp_spring_char_len','exp_spring_Fmax','exp_spring_mass'};
            %app.spring_vars = {'linear_spring_k','linear_spring_mass','linear_spring_Fmax'};
            %app.lm_vars = {'lm_linear_motor_Fmax','lm_linear_motor_Vmax','lm_linear_motor_range_of_motion','lm_linear_motor_voltage_frac'};
            app.lm_vars = {'lm_hill_motor_Fmax','lm_hill_motor_Vmax','lm_hill_motor_muscle_length','lm_hill_motor_rate_of_activation','lm_hill_motor_L_i','lm_hill_motor_a_L','lm_hill_motor_b_L','lm_hill_motor_s'};
            app.um_vars = {'um_linear_motor_Fmax','um_linear_motor_Vmax','um_linear_motor_range_of_motion','um_linear_motor_voltage_frac'};
            update_dd_vars(app);
            
            app.IV2DropDown.Items(ismember(app.IV2DropDown.Items,app.IV1DropDown.Value)) = [];
            
            waitbar(waitbar_counter/waitbar_increments,f,'Setting parameter defaults...');
            waitbar_counter = waitbar_counter + 1;
            % parameter defaults
            app.spring.SelectedTab = app.exponential_spring;
            app.loading_motor.SelectedTab = app.lm_hill_muscle_motor;
            
            % 1D plots defaults
            app.OD_IV1DropDown.Value = 'loading motor (Hill) Fmax';
            
            % 2D plots defaults
            app.IV1DropDown.Value = 'exponential spring k_0';
            app.IV2DropDown.Value = 'loading motor (Hill) Fmax';
            
            close(f);
        end

        % Button pushed function: go
        function goButtonPushed(app, event)
            lastwarn('');
            
            if (app.graphing_corner.SelectedTab == app.graphing_corner_heatmap)
                heatmap(app);
            elseif (app.graphing_corner.SelectedTab == app.graphing_corner_one_D)
                one_D_plot(app);
            elseif (app.graphing_corner.SelectedTab == app.graphing_corner_kinematics)
                kinematics(app);
            elseif (app.graphing_corner.SelectedTab == app.graphing_corner_sensitivity)
                sensitivity(app);
            end
        end

        % Value changed function: lm_hill_motor_muscle_length
        function lm_hill_motor_muscle_lengthValueChanged(app, event)
            % automatically sets lm_hill_motor_L_i to the
            % lm_hill_motor_muscle_length value
            value = app.lm_hill_motor_muscle_length.Value;
            app.lm_hill_motor_L_i.Value = value;
        end

        % Value changed function: um_hill_motor_muscle_length
        function um_hill_motor_muscle_lengthValueChanged(app, event)
            % automatically sets um_hill_motor_L_i to the
            % um_hill_motor_muscle_length value
            value = app.um_hill_motor_muscle_length.Value;
            app.um_hill_motor_L_i.Value = value;
        end

        % Value changed function: IV1DropDown
        function IV1DropDownValueChanged(app, event)
            value = app.IV1DropDown.Value;
            default = app.dd_vars_labels;
            app.IV2DropDown.Items = default;
            app.IV2DropDown.Items(ismember(app.IV2DropDown.Items,value)) = [];
        end

        % Value changed function: IV2DropDown
        function IV2DropDownValueChanged(app, event)
            value = app.IV2DropDown.Value;
            default = app.dd_vars_labels;
            app.IV1DropDown.Items = default;
            app.IV1DropDown.Items(ismember(app.IV1DropDown.Items,value)) = [];
        end

        % Selection change function: spring
        function springSelectionChanged(app, event)
            selectedTab = app.spring.SelectedTab;
            if (selectedTab == app.linear_spring)
                app.spring_vars = {'linear_spring_k','linear_spring_mass','linear_spring_Fmax'};
            elseif (selectedTab == app.exponential_spring)
                app.spring_vars = {'exp_spring_k','exp_spring_char_len','exp_spring_Fmax','exp_spring_mass'};
            elseif (selectedTab == app.linear_elastic_extensional_spring)
                app.spring_vars = {'lee_spring_E','lee_spring_A','lee_spring_L','lee_spring_rho','lee_spring_sigma_f'};
            end
            update_dd_vars(app);
        end

        % Selection change function: loading_motor
        function loading_motorSelectionChanged(app, event)
            selectedTab = app.loading_motor.SelectedTab;
            if (selectedTab == app.lm_linear_motor)
                app.lm_vars = {'lm_linear_motor_Fmax','lm_linear_motor_Vmax','lm_linear_motor_range_of_motion','lm_linear_motor_voltage_frac'};
            elseif (selectedTab == app.lm_hill_muscle_motor)
                app.lm_vars = {'lm_hill_motor_Fmax','lm_hill_motor_Vmax','lm_hill_motor_muscle_length','lm_hill_motor_rate_of_activation','lm_hill_motor_L_i','lm_hill_motor_a_L','lm_hill_motor_b_L','lm_hill_motor_s'};
            end
            update_dd_vars(app);
        end

        % Selection change function: unlatching_motor
        function unlatching_motorSelectionChanged(app, event)
            selectedTab = app.unlatching_motor.SelectedTab;
            if (selectedTab == app.um_linear_motor)
                app.um_vars = {'um_linear_motor_Fmax','um_linear_motor_Vmax','um_linear_motor_range_of_motion','um_linear_motor_voltage_frac'};
            elseif (selectedTab == app.um_hill_muscle_motor)
                app.um_vars = {'um_hill_motor_Fmax','um_hill_motor_Vmax','um_hill_motor_muscle_length','um_hill_motor_rate_of_activation','um_hill_motor_L_i','um_hill_motor_a_L','um_hill_motor_b_L','um_hill_motor_s'};
            end
            update_dd_vars(app);
        end

        % Button pushed function: ShowModelSchematicButton
        function ShowModelSchematicButtonPushed(app, event)
%             image = imread('model.pdf');
%             figure
%             imshow(image,'Border','tight','InitialMagnification','fit')
              winopen("model.pdf");
        end

        % Selection change function: graphing_corner
        function graphing_cornerSelectionChanged(app, event)
            selectedTab = app.graphing_corner.SelectedTab;
            if (selectedTab == app.graphing_corner_sensitivity)
                set(app.savesolutionCheckBox,'visible','off');
                app.go.Text = 'Compute!';
            else
                set(app.savesolutionCheckBox,'visible','on');
                app.go.Text = 'Graph!';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ParametersLabel = uilabel(app.UIFigure);
            app.ParametersLabel.Text = 'Parameters';
            app.spring = uitabgroup(app.UIFigure);
            app.spring.SelectionChangedFcn = createCallbackFcn(app, @springSelectionChanged, true);
            app.linear_spring = uitab(app.spring);
            app.linear_spring.Title = 'Linear Spring';
            app.kLabel = uilabel(app.linear_spring);
            app.kLabel.Text = 'k =';
            app.linear_spring_k = uieditfield(app.linear_spring, 'numeric');
            app.linear_spring_k.Limits = [0 Inf];
            app.linear_spring_k.Tooltip = {'stiffness of the spring'};
            app.linear_spring_k.Value = 0.5;
            app.massEditFieldLabel_4 = uilabel(app.linear_spring);
            app.massEditFieldLabel_4.Text = 'mass =';
            app.linear_spring_mass = uieditfield(app.linear_spring, 'numeric');
            app.linear_spring_mass.Limits = [0 Inf];
            app.linear_spring_mass.Tooltip = {'mass of the spring'};
            app.FmaxLabel_3 = uilabel(app.linear_spring);
            app.FmaxLabel_3.Text = 'Fmax =';
            app.linear_spring_Fmax = uieditfield(app.linear_spring, 'numeric');
            app.linear_spring_Fmax.Limits = [0 Inf];
            app.linear_spring_Fmax.Tooltip = {'max force of spring'};
            app.linear_spring_Fmax.Value = Inf;
            app.linear_elastic_extensional_spring = uitab(app.spring);
            app.linear_elastic_extensional_spring.Title = 'Linear Elastic Extensional Spring';
            app.ELabel = uilabel(app.linear_elastic_extensional_spring);
            app.ELabel.Text = 'E =';
            app.lee_spring_E = uieditfield(app.linear_elastic_extensional_spring, 'numeric');
            app.lee_spring_E.Limits = [0 Inf];
            app.lee_spring_E.Tooltip = {'modulus'};
            app.lee_spring_E.Value = 0.5;
            app.rhoLabel = uilabel(app.linear_elastic_extensional_spring);
            app.rhoLabel.Text = 'rho =';
            app.lee_spring_rho = uieditfield(app.linear_elastic_extensional_spring, 'numeric');
            app.lee_spring_rho.Limits = [0 Inf];
            app.lee_spring_rho.Tooltip = {'density'};
            app.lee_spring_rho.Value = 10;
            app.ALabel = uilabel(app.linear_elastic_extensional_spring);
            app.ALabel.Text = 'A =';
            app.lee_spring_A = uieditfield(app.linear_elastic_extensional_spring, 'numeric');
            app.lee_spring_A.Limits = [0 Inf];
            app.lee_spring_A.Tooltip = {'cross sectional area'};
            app.lee_spring_A.Value = 0.5;
            app.LLabel = uilabel(app.linear_elastic_extensional_spring);
            app.LLabel.Text = 'L =';
            app.lee_spring_L = uieditfield(app.linear_elastic_extensional_spring, 'numeric');
            app.lee_spring_L.Limits = [0 Inf];
            app.lee_spring_L.Tooltip = {'length'};
            app.lee_spring_L.Value = 0.001;
            app.sigma_fLabel = uilabel(app.linear_elastic_extensional_spring);
            app.sigma_fLabel.Text = 'sigma_f = ';
            app.lee_spring_sigma_f = uieditfield(app.linear_elastic_extensional_spring, 'numeric');
            app.lee_spring_sigma_f.Limits = [0 Inf];
            app.lee_spring_sigma_f.Tooltip = {'failure strength in Pa'};
            app.lee_spring_sigma_f.Value = Inf;
            app.exponential_spring = uitab(app.spring);
            app.exponential_spring.Title = 'Exponential Spring';
            app.k_0Label = uilabel(app.exponential_spring);
            app.k_0Label.Text = 'k_0 =';
            app.exp_spring_k = uieditfield(app.exponential_spring, 'numeric');
            app.exp_spring_k.Limits = [0 Inf];
            app.exp_spring_k.Tooltip = {'spring stiffness'};
            app.exp_spring_k.Value = 2000;
            app.characteristiclengthLabel = uilabel(app.exponential_spring);
            app.characteristiclengthLabel.Text = 'characteristic length =';
            app.exp_spring_char_len = uieditfield(app.exponential_spring, 'numeric');
            app.exp_spring_char_len.Limits = [0 Inf];
            app.exp_spring_char_len.Tooltip = {'resting length where force is 0'};
            app.exp_spring_char_len.Value = 0.001;
            app.FmaxLabel_4 = uilabel(app.exponential_spring);
            app.FmaxLabel_4.Text = 'Fmax =';
            app.exp_spring_Fmax = uieditfield(app.exponential_spring, 'numeric');
            app.exp_spring_Fmax.Limits = [0 Inf];
            app.exp_spring_Fmax.Tooltip = {'max force of the spring'};
            app.exp_spring_Fmax.Value = 20;
            app.massEditFieldLabel_5 = uilabel(app.exponential_spring);
            app.massEditFieldLabel_5.Text = 'mass =';
            app.exp_spring_mass = uieditfield(app.exponential_spring, 'numeric');
            app.exp_spring_mass.Limits = [0 Inf];
            app.exp_spring_mass.Tooltip = {'mass of the spring'};
            app.exp_spring_mass.Value = 0.002;
            app.loading_motor = uitabgroup(app.UIFigure);
            app.loading_motor.SelectionChangedFcn = createCallbackFcn(app, @loading_motorSelectionChanged, true);
            app.lm_linear_motor = uitab(app.loading_motor);
            app.lm_linear_motor.Title = 'Linear Motor';
            app.FmaxLabel_2 = uilabel(app.lm_linear_motor);
            app.FmaxLabel_2.Text = 'Fmax =';
            app.lm_linear_motor_Fmax = uieditfield(app.lm_linear_motor, 'numeric');
            app.lm_linear_motor_Fmax.Limits = [0 Inf];
            app.lm_linear_motor_Fmax.Tooltip = {'max force of the motor'};
            app.lm_linear_motor_Fmax.Value = 10;
            app.VmaxLabel_2 = uilabel(app.lm_linear_motor);
            app.VmaxLabel_2.Text = 'Vmax =';
            app.lm_linear_motor_Vmax = uieditfield(app.lm_linear_motor, 'numeric');
            app.lm_linear_motor_Vmax.Limits = [0 Inf];
            app.lm_linear_motor_Vmax.Tooltip = {'max velocity of the motor'};
            app.lm_linear_motor_Vmax.Value = 10;
            app.rangeofmotionLabel = uilabel(app.lm_linear_motor);
            app.rangeofmotionLabel.Text = 'range of motion =';
            app.lm_linear_motor_range_of_motion = uieditfield(app.lm_linear_motor, 'numeric');
            app.lm_linear_motor_range_of_motion.Limits = [0 Inf];
            app.lm_linear_motor_range_of_motion.Tooltip = {'total possible range of motion of the motor'};
            app.lm_linear_motor_range_of_motion.Value = 0.005;
            app.voltagefracLabel = uilabel(app.lm_linear_motor);
            app.voltagefracLabel.Text = 'voltage frac =';
            app.lm_linear_motor_voltage_frac = uieditfield(app.lm_linear_motor, 'numeric');
            app.lm_linear_motor_voltage_frac.Limits = [0 Inf];
            app.lm_linear_motor_voltage_frac.Tooltip = {'fraction of max voltage of motor'};
            app.lm_linear_motor_voltage_frac.Value = 1;
            app.lm_hill_muscle_motor = uitab(app.loading_motor);
            app.lm_hill_muscle_motor.Title = 'Hill Muscle Motor';
            app.FmaxLabel = uilabel(app.lm_hill_muscle_motor);
            app.FmaxLabel.Text = 'Fmax =';
            app.lm_hill_motor_Fmax = uieditfield(app.lm_hill_muscle_motor, 'numeric');
            app.lm_hill_motor_Fmax.Limits = [0 Inf];
            app.lm_hill_motor_Fmax.Tooltip = {'max force of the motor'};
            app.lm_hill_motor_Fmax.Value = 4;
            app.VmaxLabel = uilabel(app.lm_hill_muscle_motor);
            app.VmaxLabel.Text = 'Vmax =';
            app.lm_hill_motor_Vmax = uieditfield(app.lm_hill_muscle_motor, 'numeric');
            app.lm_hill_motor_Vmax.Limits = [0 Inf];
            app.lm_hill_motor_Vmax.Tooltip = {'max velocity of the motor'};
            app.lm_hill_motor_Vmax.Value = 1;
            app.musclelengthLabel = uilabel(app.lm_hill_muscle_motor);
            app.musclelengthLabel.Text = 'muscle length =';
            app.lm_hill_motor_muscle_length = uieditfield(app.lm_hill_muscle_motor, 'numeric');
            app.lm_hill_motor_muscle_length.Limits = [0 Inf];
            app.lm_hill_motor_muscle_length.ValueChangedFcn = createCallbackFcn(app, @lm_hill_motor_muscle_lengthValueChanged, true);
            app.lm_hill_motor_muscle_length.Tooltip = {''};
            app.lm_hill_motor_muscle_length.Value = 0.01;
            app.rateofactivationLabel = uilabel(app.lm_hill_muscle_motor);
            app.rateofactivationLabel.Text = 'rate of activation =';
            app.lm_hill_motor_rate_of_activation = uieditfield(app.lm_hill_muscle_motor, 'numeric');
            app.lm_hill_motor_rate_of_activation.Limits = [0 Inf];
            app.lm_hill_motor_rate_of_activation.Tooltip = {''};
            app.lm_hill_motor_rate_of_activation.Value = 200;
            app.initiallengthLabel = uilabel(app.lm_hill_muscle_motor);
            app.initiallengthLabel.Text = 'initial length =';
            app.lm_hill_motor_L_i = uieditfield(app.lm_hill_muscle_motor, 'numeric');
            app.lm_hill_motor_L_i.Limits = [0 Inf];
            app.lm_hill_motor_L_i.Tooltip = {''};
            app.lm_hill_motor_L_i.Value = 0.01;
            app.a_LLabel = uilabel(app.lm_hill_muscle_motor);
            app.a_LLabel.Text = 'a_L =';
            app.lm_hill_motor_a_L = uieditfield(app.lm_hill_muscle_motor, 'numeric');
            app.lm_hill_motor_a_L.Tooltip = {'determines shape of length-tension relationship'};
            app.lm_hill_motor_a_L.Value = 2.08;
            app.b_LLabel = uilabel(app.lm_hill_muscle_motor);
            app.b_LLabel.Text = 'b_L =';
            app.lm_hill_motor_b_L = uieditfield(app.lm_hill_muscle_motor, 'numeric');
            app.lm_hill_motor_b_L.Tooltip = {'determines shape of length-tension relationship'};
            app.lm_hill_motor_b_L.Value = -2.89;
            app.sLabel = uilabel(app.lm_hill_muscle_motor);
            app.sLabel.Text = 's =';
            app.lm_hill_motor_s = uieditfield(app.lm_hill_muscle_motor, 'numeric');
            app.lm_hill_motor_s.Tooltip = {'determines shape of length-tension relationship'};
            app.lm_hill_motor_s.Value = -0.75;
            app.latch = uitabgroup(app.UIFigure);
            app.rounded_latch = uitab(app.latch);
            app.rounded_latch.Title = 'Rounded Latch';
            app.massLabel = uilabel(app.rounded_latch);
            app.massLabel.Text = '   mass =';
            app.latch_mass = uieditfield(app.rounded_latch, 'numeric');
            app.latch_mass.Limits = [0 Inf];
            app.latch_mass.Tooltip = {'mass of the latch'};
            app.latch_mass.Value = 0.003;
            app.Label = uilabel(app.rounded_latch);
            app.Label.Text = 'μ =';
            app.latch_coeff_fric = uieditfield(app.rounded_latch, 'numeric');
            app.latch_coeff_fric.Limits = [0 Inf];
            app.latch_coeff_fric.Tooltip = {'coefficient of friction between the latch and load mass'};
            app.radiusLabel = uilabel(app.rounded_latch);
            app.radiusLabel.Text = 'radius =';
            app.latch_radius = uieditfield(app.rounded_latch, 'numeric');
            app.latch_radius.Limits = [0 Inf];
            app.latch_radius.Tooltip = {'latch radius'};
            app.latch_radius.Value = 0.005;
            app.v_0Label = uilabel(app.rounded_latch);
            app.v_0Label.Text = 'v_0 =';
            app.latch_v_0 = uieditfield(app.rounded_latch, 'numeric');
            app.latch_v_0.Limits = [0 Inf];
            app.latch_v_0.Tooltip = {'initial velocity of the latch'};
            app.minlatchingdistLabel = uilabel(app.rounded_latch);
            app.minlatchingdistLabel.Text = 'min latching dist =';
            app.min_latching_dist = uieditfield(app.rounded_latch, 'numeric');
            app.min_latching_dist.Limits = [0 Inf];
            app.min_latching_dist.Tooltip = {'lowest position the latch can engage'};
            app.maxlatchingdistLabel = uilabel(app.rounded_latch);
            app.maxlatchingdistLabel.Text = 'max latching dist =';
            app.max_latching_dist = uieditfield(app.rounded_latch, 'numeric');
            app.max_latching_dist.Limits = [0 Inf];
            app.max_latching_dist.Tooltip = {'highest position the latch can engage'};
            app.max_latching_dist.Value = Inf;
            app.runwaylengthLabel = uilabel(app.rounded_latch);
            app.runwaylengthLabel.Text = 'runway length =';
            app.runway_length = uieditfield(app.rounded_latch, 'numeric');
            app.runway_length.Limits = [0 Inf];
            app.runway_length.Tooltip = {'coefficient of friction between the latch and load mass'};
            app.load = uitabgroup(app.UIFigure);
            app.load_mass = uitab(app.load);
            app.load_mass.Title = 'Load Mass';
            app.massEditFieldLabel = uilabel(app.load_mass);
            app.massEditFieldLabel.Text = 'mass =';
            app.load_mass_mass = uieditfield(app.load_mass, 'numeric');
            app.load_mass_mass.Limits = [0 Inf];
            app.load_mass_mass.Tooltip = {'mass of the load'};
            app.load_mass_mass.Value = 0.01;
            app.massofleverarmLabel = uilabel(app.load_mass);
            app.massofleverarmLabel.Text = 'mass of lever arm =';
            app.load_m_rod = uieditfield(app.load_mass, 'numeric');
            app.load_m_rod.Limits = [0 Inf];
            app.load_m_rod.Tooltip = {''};
            app.EMALabel = uilabel(app.load_mass);
            app.EMALabel.Text = 'EMA =';
            app.load_EMA = uieditfield(app.load_mass, 'numeric');
            app.load_EMA.Limits = [0.001 Inf];
            app.load_EMA.Tooltip = {'effective mechanical advantage'};
            app.load_EMA.Value = 1;
            app.LoadingMotorLabel = uilabel(app.UIFigure);
            app.LoadingMotorLabel.Text = 'Loading Motor:';
            app.unlatching_motor = uitabgroup(app.UIFigure);
            app.unlatching_motor.SelectionChangedFcn = createCallbackFcn(app, @unlatching_motorSelectionChanged, true);
            app.um_linear_motor = uitab(app.unlatching_motor);
            app.um_linear_motor.Title = 'Linear Motor';
            app.FmaxLabel_5 = uilabel(app.um_linear_motor);
            app.FmaxLabel_5.Text = 'Fmax =';
            app.um_linear_motor_Fmax = uieditfield(app.um_linear_motor, 'numeric');
            app.um_linear_motor_Fmax.Limits = [0 Inf];
            app.um_linear_motor_Fmax.Tooltip = {'max force of the motor'};
            app.um_linear_motor_Fmax.Value = 0.25;
            app.VmaxLabel_3 = uilabel(app.um_linear_motor);
            app.VmaxLabel_3.Text = 'Vmax =';
            app.um_linear_motor_Vmax = uieditfield(app.um_linear_motor, 'numeric');
            app.um_linear_motor_Vmax.Limits = [0 Inf];
            app.um_linear_motor_Vmax.Tooltip = {'max velocity of the motor'};
            app.um_linear_motor_Vmax.Value = 1;
            app.rangeofmotionLabel_2 = uilabel(app.um_linear_motor);
            app.rangeofmotionLabel_2.Text = 'range of motion =';
            app.um_linear_motor_range_of_motion = uieditfield(app.um_linear_motor, 'numeric');
            app.um_linear_motor_range_of_motion.Limits = [0 Inf];
            app.um_linear_motor_range_of_motion.Tooltip = {'total possible range of motion of the motor'};
            app.um_linear_motor_range_of_motion.Value = 0.005;
            app.voltagefracLabel_2 = uilabel(app.um_linear_motor);
            app.voltagefracLabel_2.Text = 'voltage frac =';
            app.um_linear_motor_voltage_frac = uieditfield(app.um_linear_motor, 'numeric');
            app.um_linear_motor_voltage_frac.Limits = [0 Inf];
            app.um_linear_motor_voltage_frac.Tooltip = {'fraction of max voltage of motor'};
            app.um_linear_motor_voltage_frac.Value = 1;
            app.um_hill_muscle_motor = uitab(app.unlatching_motor);
            app.um_hill_muscle_motor.Title = 'Hill Muscle Motor';
            app.FmaxLabel_6 = uilabel(app.um_hill_muscle_motor);
            app.FmaxLabel_6.Text = 'Fmax =';
            app.um_hill_motor_Fmax = uieditfield(app.um_hill_muscle_motor, 'numeric');
            app.um_hill_motor_Fmax.Limits = [0 Inf];
            app.um_hill_motor_Fmax.Tooltip = {'max force of the motor'};
            app.um_hill_motor_Fmax.Value = 10;
            app.VmaxLabel_4 = uilabel(app.um_hill_muscle_motor);
            app.VmaxLabel_4.Text = 'Vmax =';
            app.um_hill_motor_Vmax = uieditfield(app.um_hill_muscle_motor, 'numeric');
            app.um_hill_motor_Vmax.Limits = [0 Inf];
            app.um_hill_motor_Vmax.Tooltip = {'max velocity of the motor'};
            app.um_hill_motor_Vmax.Value = 10;
            app.musclelengthLabel_2 = uilabel(app.um_hill_muscle_motor);
            app.musclelengthLabel_2.Text = 'muscle length =';
            app.um_hill_motor_muscle_length = uieditfield(app.um_hill_muscle_motor, 'numeric');
            app.um_hill_motor_muscle_length.Limits = [0 Inf];
            app.um_hill_motor_muscle_length.ValueChangedFcn = createCallbackFcn(app, @um_hill_motor_muscle_lengthValueChanged, true);
            app.um_hill_motor_muscle_length.Tooltip = {''};
            app.um_hill_motor_muscle_length.Value = 4;
            app.rateofactivationLabel_2 = uilabel(app.um_hill_muscle_motor);
            app.rateofactivationLabel_2.Text = 'rate of activation =';
            app.um_hill_motor_rate_of_activation = uieditfield(app.um_hill_muscle_motor, 'numeric');
            app.um_hill_motor_rate_of_activation.Limits = [0 Inf];
            app.um_hill_motor_rate_of_activation.Tooltip = {''};
            app.um_hill_motor_rate_of_activation.Value = 2;
            app.initiallengthLabel_2 = uilabel(app.um_hill_muscle_motor);
            app.initiallengthLabel_2.Text = 'initial length =';
            app.um_hill_motor_L_i = uieditfield(app.um_hill_muscle_motor, 'numeric');
            app.um_hill_motor_L_i.Limits = [0 Inf];
            app.um_hill_motor_L_i.Tooltip = {''};
            app.um_hill_motor_L_i.Value = 4;
            app.a_LLabel_2 = uilabel(app.um_hill_muscle_motor);
            app.a_LLabel_2.Text = 'a_L =';
            app.um_hill_motor_a_L = uieditfield(app.um_hill_muscle_motor, 'numeric');
            app.um_hill_motor_a_L.Tooltip = {'determines shape of length-tension relationship'};
            app.um_hill_motor_a_L.Value = 2.08;
            app.b_LLabel_2 = uilabel(app.um_hill_muscle_motor);
            app.b_LLabel_2.Text = 'b_L =';
            app.um_hill_motor_b_L = uieditfield(app.um_hill_muscle_motor, 'numeric');
            app.um_hill_motor_b_L.Tooltip = {'determines shape of length-tension relationship'};
            app.um_hill_motor_b_L.Value = -2.89;
            app.sLabel_2 = uilabel(app.um_hill_muscle_motor);
            app.sLabel_2.Text = 's =';
            app.um_hill_motor_s = uieditfield(app.um_hill_muscle_motor, 'numeric');
            app.um_hill_motor_s.Tooltip = {'determines shape of length-tension relationship'};
            app.um_hill_motor_s.Value = -0.75;
            app.UnlatchingMotorLabel = uilabel(app.UIFigure);
            app.UnlatchingMotorLabel.Text = 'Unlatching Motor:';
            app.GraphingCornerLabel = uilabel(app.UIFigure);
            app.GraphingCornerLabel.Text = 'Graphing Corner';
            app.graphing_corner = uitabgroup(app.UIFigure);
            app.graphing_corner.SelectionChangedFcn = createCallbackFcn(app, @graphing_cornerSelectionChanged, true);
            app.graphing_corner_kinematics = uitab(app.graphing_corner);
            app.graphing_corner_kinematics.Title = 'Kinematics';
            app.KinematicsOutputOptionsLabel = uilabel(app.graphing_corner_kinematics);
            app.KinematicsOutputOptionsLabel.Text = 'Kinematics Output Options';
            app.forcedisp = uicheckbox(app.graphing_corner_kinematics);
            app.forcedisp.Text = 'motor and spring force displacement curves';
            app.forcedisp.Value = true;
            app.latchkinematicsCheckBox = uicheckbox(app.graphing_corner_kinematics);
            app.latchkinematicsCheckBox.Text = 'latch kinematics';
            app.latchkinematicsCheckBox.Value = true;
            app.loadkinematicsCheckBox = uicheckbox(app.graphing_corner_kinematics);
            app.loadkinematicsCheckBox.Text = 'load kinematics';
            app.loadkinematicsCheckBox.Value = true;
            app.LoadingphaseLabel = uilabel(app.graphing_corner_kinematics);
            app.LoadingphaseLabel.Text = 'Loading phase:';
            app.UnlatchingandlaunchingphaseLabel = uilabel(app.graphing_corner_kinematics);
            app.UnlatchingandlaunchingphaseLabel.Text = 'Unlatching and launching phase:';
            app.graphing_corner_one_D = uitab(app.graphing_corner);
            app.graphing_corner_one_D.Title = '1D Plot';
            app.OD_a_maxCheckBox = uicheckbox(app.graphing_corner_one_D);
            app.OD_a_maxCheckBox.Text = 'a_max';
            app.xaxisLabel_2 = uilabel(app.graphing_corner_one_D);
            app.xaxisLabel_2.Text = 'x-axis';
            app.OD_y_maxCheckBox = uicheckbox(app.graphing_corner_one_D);
            app.OD_y_maxCheckBox.Text = 'y_max';
            app.OD_y_unlatchCheckBox = uicheckbox(app.graphing_corner_one_D);
            app.OD_y_unlatchCheckBox.Text = 'y_unlatch';
            app.OD_t_LCheckBox = uicheckbox(app.graphing_corner_one_D);
            app.OD_t_LCheckBox.Text = 't_L';
            app.OD_v_toCheckBox = uicheckbox(app.graphing_corner_one_D);
            app.OD_v_toCheckBox.Text = 'v_to';
            app.OD_P_maxCheckBox = uicheckbox(app.graphing_corner_one_D);
            app.OD_P_maxCheckBox.Text = 'P_max';
            app.OD_P_maxCheckBox.Value = true;
            app.OD_t_toCheckBox = uicheckbox(app.graphing_corner_one_D);
            app.OD_t_toCheckBox.Text = 't_to';
            app.OD_KE_maxCheckBox = uicheckbox(app.graphing_corner_one_D);
            app.OD_KE_maxCheckBox.Text = 'KE_max';
            app.OD_IV1DropDown = uidropdown(app.graphing_corner_one_D);
            app.OD_IV1DropDown.Items = {'--no selection--'};
            app.OD_IV1DropDown.Tooltip = {'Independent Variable 1'};
            app.OD_IV1DropDown.Value = '--no selection--';
            app.yaxisOutputOptionsLabel = uilabel(app.graphing_corner_one_D);
            app.yaxisOutputOptionsLabel.Text = 'y-axis Output Options';
            app.pixelsofresolutionLabel_2 = uilabel(app.graphing_corner_one_D);
            app.pixelsofresolutionLabel_2.Text = 'pixels of resolution =';
            app.OD_n = uieditfield(app.graphing_corner_one_D, 'numeric');
            app.OD_n.Limits = [1 1024];
            app.OD_n.RoundFractionalValues = 'on';
            app.OD_n.ValueDisplayFormat = '%.0f';
            app.OD_n.Value = 100;
            app.xminEditFieldLabel_2 = uilabel(app.graphing_corner_one_D);
            app.xminEditFieldLabel_2.Text = 'x min';
            app.OD_xmin = uieditfield(app.graphing_corner_one_D, 'numeric');
            app.OD_xmin.Value = 0.01;
            app.xmaxEditFieldLabel_2 = uilabel(app.graphing_corner_one_D);
            app.xmaxEditFieldLabel_2.Text = 'x max';
            app.OD_xmax = uieditfield(app.graphing_corner_one_D, 'numeric');
            app.OD_xmax.Value = 12;
            app.OD_x_log_space = uiswitch(app.graphing_corner_one_D, 'slider');
            app.OD_x_log_space.Items = {'lin', 'log'};
            app.OD_x_log_space.Value = 'lin';
            app.ComparetoDirectActuationCheckBox = uicheckbox(app.graphing_corner_one_D);
            app.ComparetoDirectActuationCheckBox.Text = 'Compare to Direct Actuation';
            app.ComparetoDirectActuationCheckBox.Value = true;
            app.graphing_corner_heatmap = uitab(app.graphing_corner);
            app.graphing_corner_heatmap.Title = '2D Plot';
            app.a_maxCheckBox = uicheckbox(app.graphing_corner_heatmap);
            app.a_maxCheckBox.Text = 'a_max';
            app.a_maxCheckBox.Value = true;
            app.xaxisLabel = uilabel(app.graphing_corner_heatmap);
            app.xaxisLabel.Text = 'x-axis';
            app.yaxisLabel = uilabel(app.graphing_corner_heatmap);
            app.yaxisLabel.Text = 'y-axis';
            app.y_maxCheckBox = uicheckbox(app.graphing_corner_heatmap);
            app.y_maxCheckBox.Text = 'y_max';
            app.y_unlatchCheckBox = uicheckbox(app.graphing_corner_heatmap);
            app.y_unlatchCheckBox.Text = 'y_unlatch';
            app.t_LCheckBox = uicheckbox(app.graphing_corner_heatmap);
            app.t_LCheckBox.Text = 't_L';
            app.v_toCheckBox = uicheckbox(app.graphing_corner_heatmap);
            app.v_toCheckBox.Text = 'v_to';
            app.v_toCheckBox.Value = true;
            app.P_maxCheckBox = uicheckbox(app.graphing_corner_heatmap);
            app.P_maxCheckBox.Text = 'P_max';
            app.P_maxCheckBox.Value = true;
            app.t_toCheckBox = uicheckbox(app.graphing_corner_heatmap);
            app.t_toCheckBox.Text = 't_to';
            app.t_toCheckBox.Value = true;
            app.KE_maxCheckBox = uicheckbox(app.graphing_corner_heatmap);
            app.KE_maxCheckBox.Text = 'KE_max';
            app.IV1DropDown = uidropdown(app.graphing_corner_heatmap);
            app.IV1DropDown.Items = {'--no selection--'};
            app.IV1DropDown.ValueChangedFcn = createCallbackFcn(app, @IV1DropDownValueChanged, true);
            app.IV1DropDown.Tooltip = {'Independent Variable 1'};
            app.IV1DropDown.Value = '--no selection--';
            app.IV2DropDown = uidropdown(app.graphing_corner_heatmap);
            app.IV2DropDown.Items = {'--no selection--'};
            app.IV2DropDown.ValueChangedFcn = createCallbackFcn(app, @IV2DropDownValueChanged, true);
            app.IV2DropDown.Value = '--no selection--';
            app.xminEditFieldLabel = uilabel(app.graphing_corner_heatmap);
            app.xminEditFieldLabel.Text = 'x min';
            app.xmin = uieditfield(app.graphing_corner_heatmap, 'numeric');
            app.xmin.Value = 100;
            app.xmaxEditFieldLabel = uilabel(app.graphing_corner_heatmap);
            app.xmaxEditFieldLabel.Text = 'x max';
            app.xmax = uieditfield(app.graphing_corner_heatmap, 'numeric');
            app.xmax.Value = 300000;
            app.yminLabel = uilabel(app.graphing_corner_heatmap);
            app.yminLabel.Text = 'y min';
            app.ymin = uieditfield(app.graphing_corner_heatmap, 'numeric');
            app.ymin.Value = 1;
            app.ymaxLabel = uilabel(app.graphing_corner_heatmap);
            app.ymaxLabel.Text = 'y max';
            app.ymax = uieditfield(app.graphing_corner_heatmap, 'numeric');
            app.ymax.Value = 1000;
            app.HeatmapOutputOptionsLabel = uilabel(app.graphing_corner_heatmap);
            app.HeatmapOutputOptionsLabel.Text = 'Heatmap Output Options';
            app.pixelsofresolutionLabel = uilabel(app.graphing_corner_heatmap);
            app.pixelsofresolutionLabel.Text = 'pixels of resolution =';
            app.n = uieditfield(app.graphing_corner_heatmap, 'numeric');
            app.n.Limits = [1 1024];
            app.n.RoundFractionalValues = 'on';
            app.n.ValueDisplayFormat = '%.0f';
            app.n.Value = 20;
            app.x_log_space = uiswitch(app.graphing_corner_heatmap, 'slider');
            app.x_log_space.Items = {'lin', 'log'};
            app.x_log_space.Value = 'log';
            app.y_log_space = uiswitch(app.graphing_corner_heatmap, 'slider');
            app.y_log_space.Items = {'lin', 'log'};
            app.y_log_space.Value = 'log';
            app.MakeLaMSAZonePlotCheckBox = uicheckbox(app.graphing_corner_heatmap);
            app.MakeLaMSAZonePlotCheckBox.Text = 'Make LaMSA Zone Plot';
            app.MakeLaMSAZonePlotCheckBox.Value = true;
            app.graphing_corner_sensitivity = uitab(app.graphing_corner);
            app.graphing_corner_sensitivity.Title = 'Sensitivity';
            app.VariablesListBoxLabel = uilabel(app.graphing_corner_sensitivity);
            app.VariablesListBoxLabel.Text = 'Variables';
            app.sensitivity_vars = uilistbox(app.graphing_corner_sensitivity);
            app.pressCTRLtoselectmultipleLabel = uilabel(app.graphing_corner_sensitivity);
            app.pressCTRLtoselectmultipleLabel.Text = {'(press '; 'CTRL to '; 'select '; 'multiple)'};
            app.MetricButtonGroup = uibuttongroup(app.graphing_corner_sensitivity);
            app.MetricButtonGroup.Title = 'Metric';
            app.ymaxButton = uiradiobutton(app.MetricButtonGroup);
            app.ymaxButton.Text = 'ymax';
            app.yunlatchButton = uiradiobutton(app.MetricButtonGroup);
            app.yunlatchButton.Text = 'yunlatch';
            app.tLButton = uiradiobutton(app.MetricButtonGroup);
            app.tLButton.Text = 'tL';
            app.vtoButton = uiradiobutton(app.MetricButtonGroup);
            app.vtoButton.Text = 'vto';
            app.PmaxButton = uiradiobutton(app.MetricButtonGroup);
            app.PmaxButton.Text = 'Pmax';
            app.PmaxButton.Value = true;
            app.ttoButton = uiradiobutton(app.MetricButtonGroup);
            app.ttoButton.Text = 'tto';
            app.KEmaxButton = uiradiobutton(app.MetricButtonGroup);
            app.KEmaxButton.Text = 'KEmax';
            app.go = uibutton(app.UIFigure, 'push');
            app.go.ButtonPushedFcn = createCallbackFcn(app, @goButtonPushed, true);
            app.go.Text = 'Graph!';
            app.ShowModelSchematicButton = uibutton(app.UIFigure, 'push');
            app.ShowModelSchematicButton.ButtonPushedFcn = createCallbackFcn(app, @ShowModelSchematicButtonPushed, true);
            app.ShowModelSchematicButton.Text = 'Show Model Schematic';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'lamsa.PNG';
            app.savesolutionCheckBox = uicheckbox(app.UIFigure);
            app.savesolutionCheckBox.Text = 'save solution?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plot_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end