classdef apptrial1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        go                            matlab.ui.control.Button
        LaMSAModelLabel               matlab.ui.control.Label
        spring                        matlab.ui.container.TabGroup
        linear_spring                 matlab.ui.container.Tab
        kLabel                        matlab.ui.control.Label
        linear_spring_k               matlab.ui.control.NumericEditField
        massEditFieldLabel_4          matlab.ui.control.Label
        linear_spring_mass            matlab.ui.control.NumericEditField
        FmaxLabel_3                   matlab.ui.control.Label
        linear_spring_Fmax            matlab.ui.control.NumericEditField
        RequiredFieldsLabel_2         matlab.ui.control.Label
        OptionalFieldsLabel_2         matlab.ui.control.Label
        defaultvaluesfilledinLabel_2  matlab.ui.control.Label
        exponential_spring            matlab.ui.container.Tab
        k_0Label                      matlab.ui.control.Label
        exp_spring_k                  matlab.ui.control.NumericEditField
        characteristiclengthLabel     matlab.ui.control.Label
        exp_spring_char_len           matlab.ui.control.NumericEditField
        FmaxLabel_4                   matlab.ui.control.Label
        exp_spring_Fmax               matlab.ui.control.NumericEditField
        massEditFieldLabel_5          matlab.ui.control.Label
        exp_spring_mass               matlab.ui.control.NumericEditField
        RequiredFieldsLabel           matlab.ui.control.Label
        OptionalFieldsLabel           matlab.ui.control.Label
        defaultvaluesfilledinLabel    matlab.ui.control.Label
        loading_motor                 matlab.ui.container.TabGroup
        lm_linear_motor               matlab.ui.container.Tab
        FmaxLabel_2                   matlab.ui.control.Label
        lm_linear_motor_Fmax          matlab.ui.control.NumericEditField
        VmaxLabel_2                   matlab.ui.control.Label
        lm_linear_motor_Vmax          matlab.ui.control.NumericEditField
        RequiredFieldsLabel_3         matlab.ui.control.Label
        rangeofmotionLabel            matlab.ui.control.Label
        lm_linear_motor_range_of_motion  matlab.ui.control.NumericEditField
        lm_hill_muscle_motor          matlab.ui.container.Tab
        FmaxLabel                     matlab.ui.control.Label
        lm_hill_motor_Fmax            matlab.ui.control.NumericEditField
        VmaxLabel                     matlab.ui.control.Label
        lm_hill_motor_Vmax            matlab.ui.control.NumericEditField
        musclelengthLabel             matlab.ui.control.Label
        lm_hill_motor_muscle_length   matlab.ui.control.NumericEditField
        RequiredFieldsLabel_4         matlab.ui.control.Label
        OptionalFieldsLabel_4         matlab.ui.control.Label
        defaultvaluesfilledinLabel_4  matlab.ui.control.Label
        rateofactivationLabel         matlab.ui.control.Label
        lm_hill_motor_rate_of_activation  matlab.ui.control.NumericEditField
        initiallengthLabel            matlab.ui.control.Label
        lm_hill_motor_L_i             matlab.ui.control.NumericEditField
        a_LLabel                      matlab.ui.control.Label
        lm_hill_motor_a_L             matlab.ui.control.NumericEditField
        b_LLabel                      matlab.ui.control.Label
        lm_hill_motor_b_L             matlab.ui.control.NumericEditField
        sLabel                        matlab.ui.control.Label
        lm_hill_motor_s               matlab.ui.control.NumericEditField
        latch                         matlab.ui.container.TabGroup
        rounded_latch                 matlab.ui.container.Tab
        RequiredFieldsLabel_5         matlab.ui.control.Label
        OptionalFieldsLabel_5         matlab.ui.control.Label
        defaultvaluesfilledinLabel_5  matlab.ui.control.Label
        massEditFieldLabel_2          matlab.ui.control.Label
        latch_mass                    matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        latch_coeff_fric              matlab.ui.control.NumericEditField
        radiusLabel                   matlab.ui.control.Label
        latch_radius                  matlab.ui.control.NumericEditField
        v_0Label                      matlab.ui.control.Label
        latch_v_0                     matlab.ui.control.NumericEditField
        load                          matlab.ui.container.TabGroup
        load_mass                     matlab.ui.container.Tab
        RequiredFieldsLabel_6         matlab.ui.control.Label
        massEditFieldLabel            matlab.ui.control.Label
        load_mass_mass                matlab.ui.control.NumericEditField
        LoadingMotorLabel             matlab.ui.control.Label
        unlatching_motor              matlab.ui.container.TabGroup
        um_linear_motor               matlab.ui.container.Tab
        FmaxLabel_5                   matlab.ui.control.Label
        um_linear_motor_Fmax          matlab.ui.control.NumericEditField
        VmaxLabel_3                   matlab.ui.control.Label
        um_linear_motor_Vmax          matlab.ui.control.NumericEditField
        RequiredFieldsLabel_7         matlab.ui.control.Label
        rangeofmotionLabel_2          matlab.ui.control.Label
        um_linear_motor_range_of_motion  matlab.ui.control.NumericEditField
        um_hill_muscle_motor          matlab.ui.container.Tab
        FmaxLabel_6                   matlab.ui.control.Label
        um_hill_motor_Fmax            matlab.ui.control.NumericEditField
        VmaxLabel_4                   matlab.ui.control.Label
        um_hill_motor_Vmax            matlab.ui.control.NumericEditField
        musclelengthLabel_2           matlab.ui.control.Label
        um_hill_motor_muscle_length   matlab.ui.control.NumericEditField
        RequiredFieldsLabel_8         matlab.ui.control.Label
        OptionalFieldsLabel_6         matlab.ui.control.Label
        defaultvaluesfilledinLabel_6  matlab.ui.control.Label
        rateofactivationLabel_2       matlab.ui.control.Label
        um_hill_motor_rate_of_activation  matlab.ui.control.NumericEditField
        initiallengthLabel_2          matlab.ui.control.Label
        um_hill_motor_L_i             matlab.ui.control.NumericEditField
        a_LLabel_2                    matlab.ui.control.Label
        um_hill_motor_a_L             matlab.ui.control.NumericEditField
        b_LLabel_2                    matlab.ui.control.Label
        um_hill_motor_b_L             matlab.ui.control.NumericEditField
        sLabel_2                      matlab.ui.control.Label
        um_hill_motor_s               matlab.ui.control.NumericEditField
        UnlatchingMotorLabel          matlab.ui.control.Label
        IV1DropDownLabel              matlab.ui.control.Label
        IV1DropDown                   matlab.ui.control.DropDown
        IV2DropDownLabel              matlab.ui.control.Label
        IV2DropDown                   matlab.ui.control.DropDown
        xminEditFieldLabel            matlab.ui.control.Label
        xmin                          matlab.ui.control.NumericEditField
        xmaxEditFieldLabel            matlab.ui.control.Label
        xmax                          matlab.ui.control.NumericEditField
        xminEditFieldLabel_2          matlab.ui.control.Label
        ymin                          matlab.ui.control.NumericEditField
        xmaxEditFieldLabel_2          matlab.ui.control.Label
        ymax                          matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        f  % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: go
        function goButtonPushed(app, event)
            addpath(genpath(pwd));
            
            % output directory initialization
            dateString = string(datetime);
            cleanDateString = regexprep(dateString, " ", "_");
            cleanDateString = regexprep(cleanDateString, ":", "_");
            
            
            %% plot parameters
            
            N=1; % determines resolution of heatplots

            % setting x axis on the plot (Fmax of latch)
            xname = 'Fmax';
            xrange = [app.xmin.Value app.xmax.Value];
            looping_value_x = logspace(xrange(1),xrange(2),N); % looping value x
            
            %setting y axis value on plot (Vmax of latch)
            yname = 'vmax';
            yrange = [app.ymin.Value app.ymax.Value];
            looping_value_y = logspace(yrange(1),yrange(2),N);
            
            metrics = {'tto','vto','Pmax','ymax','tL','KEmax','yunlatch'};
            
            looping_param_x = app.IV1DropDown.Value;
            looping_param_y = app.IV2DropDown.Value;
            
            for ii=1:length(metrics)
                outval{ii}=zeros(N);
            end
            for i=1:N %iterate over y-axis-variable of plot
                for j=1:N %iterate over x-axis-variable of plot       
                    
                    eval(['app,' looping_param_x '.Value = ' num2str(looping_value_x(j))]);
                    eval(['app,' looping_param_y '.Value = ' num2str(looping_value_y(i))]);
                    
                    %% initializing LaMSA component structs
            
                    % load mass struct initialization
                    load = load_mass(app.load_mass_mass.Value);
                    
                    % latch struct initialization
                    latch = rounded_latch(app.latch_radius.Value,app.latch_mass.Value,app.latch_coeff_fric.Value, app.latch_v_0.Value);
                    
                    % spring struct initialization
                    if (app.spring.SelectedTab == app.linear_spring)
                        spring = linear_spring(app.linear_spring_k.Value,app.linear_spring_mass.Value,app.linear_spring_Fmax.Value);
                    elseif (app.spring.SelectedTab == app.exponential_spring)
                        spring = exponential_spring(app.exp_spring_k.Value,app.exp_spring_char_len.Value,app.exp_spring_mass.Value,app.exp_spring_Fmax.Value);
                    end
                    
                    % loading motor struct initialization
                    if (app.loading_motor.SelectedTab == app.lm_linear_motor)
                        loading_motor = linear_motor(app.lm_linear_motor_Fmax.Value,app.lm_linear_motor_Vmax.Value,app.lm_linear_motor_range_of_motion.Value);
                    elseif (app.loading_motor.SelectedTab == app.lm_hill_muscle_motor)
                        loading_motor = hill_muscle_motor(app.lm_hill_motor_muscle_length.Value,app.lm_hill_motor_Fmax.Value,app.lm_hill_motor_Vmax.Value,app.lm_hill_motor_rate_of_activation.Value,app.lm_hill_motor_L_i.Value,app.lm_hill_motor_a_L.Value,app.lm_hill_motor_b_L.Value,app.lm_hill_motor_s.Value);
                    end
                    
                    % unlatching motor struct initialization
                    if (app.unlatching_motor.SelectedTab == app.um_linear_motor)
                        unlatching_motor = linear_motor(app.um_linear_motor_Fmax.Value,app.um_linear_motor_Vmax.Value,app.um_linear_motor_range_of_motion.Value);
                    elseif (app.unlatching_motor.SelectedTab == app.um_hill_muscle_motor)
                        unlatching_motor = hill_muscle_motor(app.um_hill_motor_muscle_length.Value,app.um_hill_motor_Fmax.Value,app.um_hill_motor_Vmax.Value,app.um_hill_motor_rate_of_activation.Value,app.um_hill_motor_L_i.Value,app.um_hill_motor_a_L.Value,app.um_hill_motor_b_L.Value,app.um_hill_motor_s.Value);
                    end
                    
                    % calling solve model
                    [sol,transition_times] = solve_model(loading_motor,unlatching_motor,load,latch,spring,cleanDateString);
                    met_dict=get_metrics(sol,transition_times,load,metrics);
                    for ii=1:length(metrics)
                        outval{ii}(i,j)=met_dict(metrics{ii});
                    end
                end
                disp(['row ' num2str(i) ' of ' num2str(N)]);
            end
               
            % plot output
            figure
            n=1;
            for ii=1:length(metrics)
                subplot(2,4,n);
                imagesc(xrange,yrange,outval{ii});
                set(gca,'YDir','normal');
                xlabel(xname,'Interpreter', 'Latex');
                ylabel(yname, 'Interpreter', 'Latex');
                c = colorbar;
                c.Label.String = metrics{ii};
                n=n+1;
            end

        end

        % Value changed function: lm_hill_motor_muscle_length
        function lm_hill_motor_muscle_lengthValueChanged(app, event)
            % automatically sets lm_hill_motor_L_i to the
            % lm_hill_motor_muscle_length value
            value = app.lm_hill_motor_muscle_length.Value;
            app.lm_hill_motor_L_i.Value = value;
            app.f
        end

        % Value changed function: um_hill_motor_muscle_length
        function um_hill_motor_muscle_lengthValueChanged(app, event)
            % automatically sets um_hill_motor_L_i to the
            % um_hill_motor_muscle_length value
            value = app.um_hill_motor_muscle_length.Value;
            app.um_hill_motor_L_i.Value = value;
        end

        % Value changed function: IV1DropDown
        function IV1DropDownValueChanged(app, event)
            app.f.spring = 0;
            default = {'--no selection--','load_mass_mass','latch_mass'};
            value = app.IV1DropDown.Value;
            app.IV2DropDown.Items = default;
            app.IV2DropDown.Items(ismember(app.IV2DropDown.Items,value)) = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.go = uibutton(app.UIFigure, 'push');
            app.go.ButtonPushedFcn = createCallbackFcn(app, @goButtonPushed, true);
            app.go.Text = 'Graph!';
            app.LaMSAModelLabel = uilabel(app.UIFigure);
            app.LaMSAModelLabel.Text = 'LaMSA Model';
            app.spring = uitabgroup(app.UIFigure);
            app.linear_spring = uitab(app.spring);
            app.linear_spring.Title = 'Linear Spring';
            app.kLabel = uilabel(app.linear_spring);
            app.kLabel.Text = 'k =';
            app.linear_spring_k = uieditfield(app.linear_spring, 'numeric');
            app.linear_spring_k.Limits = [0 Inf];
            app.linear_spring_k.Tooltip = {''};
            app.linear_spring_k.Value = 0.5;
            app.massEditFieldLabel_4 = uilabel(app.linear_spring);
            app.massEditFieldLabel_4.Text = 'mass =';
            app.linear_spring_mass = uieditfield(app.linear_spring, 'numeric');
            app.linear_spring_mass.Limits = [0 Inf];
            app.linear_spring_mass.Tooltip = {''};
            app.FmaxLabel_3 = uilabel(app.linear_spring);
            app.FmaxLabel_3.Text = 'Fmax =';
            app.linear_spring_Fmax = uieditfield(app.linear_spring, 'numeric');
            app.linear_spring_Fmax.Limits = [0 Inf];
            app.linear_spring_Fmax.Tooltip = {''};
            app.linear_spring_Fmax.Value = Inf;
            app.RequiredFieldsLabel_2 = uilabel(app.linear_spring);
            app.RequiredFieldsLabel_2.Text = 'Required Fields:';
            app.OptionalFieldsLabel_2 = uilabel(app.linear_spring);
            app.OptionalFieldsLabel_2.Text = 'Optional Fields:';
            app.defaultvaluesfilledinLabel_2 = uilabel(app.linear_spring);
            app.defaultvaluesfilledinLabel_2.Text = '(default values filled in)';
            app.exponential_spring = uitab(app.spring);
            app.exponential_spring.Title = 'Exponential Spring';
            app.k_0Label = uilabel(app.exponential_spring);
            app.k_0Label.Text = 'k_0 =';
            app.exp_spring_k = uieditfield(app.exponential_spring, 'numeric');
            app.exp_spring_k.Limits = [0 Inf];
            app.exp_spring_k.Tooltip = {''};
            app.characteristiclengthLabel = uilabel(app.exponential_spring);
            app.characteristiclengthLabel.Text = 'characteristic length =';
            app.exp_spring_char_len = uieditfield(app.exponential_spring, 'numeric');
            app.exp_spring_char_len.Limits = [0 Inf];
            app.exp_spring_char_len.Tooltip = {''};
            app.FmaxLabel_4 = uilabel(app.exponential_spring);
            app.FmaxLabel_4.Text = 'Fmax =';
            app.exp_spring_Fmax = uieditfield(app.exponential_spring, 'numeric');
            app.exp_spring_Fmax.Limits = [0 Inf];
            app.exp_spring_Fmax.Tooltip = {''};
            app.exp_spring_Fmax.Value = Inf;
            app.massEditFieldLabel_5 = uilabel(app.exponential_spring);
            app.massEditFieldLabel_5.Text = 'mass =';
            app.exp_spring_mass = uieditfield(app.exponential_spring, 'numeric');
            app.exp_spring_mass.Limits = [0 Inf];
            app.exp_spring_mass.Tooltip = {''};
            app.RequiredFieldsLabel = uilabel(app.exponential_spring);
            app.RequiredFieldsLabel.Text = 'Required Fields:';
            app.OptionalFieldsLabel = uilabel(app.exponential_spring);
            app.OptionalFieldsLabel.Text = 'Optional Fields:';
            app.defaultvaluesfilledinLabel = uilabel(app.exponential_spring);
            app.defaultvaluesfilledinLabel.Text = '(default values filled in)';
            app.loading_motor = uitabgroup(app.UIFigure);
            app.lm_linear_motor = uitab(app.loading_motor);
            app.lm_linear_motor.Title = 'Linear Motor';
            app.FmaxLabel_2 = uilabel(app.lm_linear_motor);
            app.FmaxLabel_2.Text = 'Fmax =';
            app.lm_linear_motor_Fmax = uieditfield(app.lm_linear_motor, 'numeric');
            app.lm_linear_motor_Fmax.Limits = [0 Inf];
            app.lm_linear_motor_Fmax.Tooltip = {''};
            app.lm_linear_motor_Fmax.Value = 10;
            app.VmaxLabel_2 = uilabel(app.lm_linear_motor);
            app.VmaxLabel_2.Text = 'Vmax =';
            app.lm_linear_motor_Vmax = uieditfield(app.lm_linear_motor, 'numeric');
            app.lm_linear_motor_Vmax.Limits = [0 Inf];
            app.lm_linear_motor_Vmax.Tooltip = {''};
            app.lm_linear_motor_Vmax.Value = 10;
            app.RequiredFieldsLabel_3 = uilabel(app.lm_linear_motor);
            app.RequiredFieldsLabel_3.Text = 'Required Fields:';
            app.rangeofmotionLabel = uilabel(app.lm_linear_motor);
            app.rangeofmotionLabel.Text = 'range of motion =';
            app.lm_linear_motor_range_of_motion = uieditfield(app.lm_linear_motor, 'numeric');
            app.lm_linear_motor_range_of_motion.Limits = [0 Inf];
            app.lm_linear_motor_range_of_motion.Tooltip = {''};
            app.lm_linear_motor_range_of_motion.Value = 3;
            app.lm_hill_muscle_motor = uitab(app.loading_motor);
            app.lm_hill_muscle_motor.Title = 'Hill Muscle Motor';
            app.FmaxLabel = uilabel(app.lm_hill_muscle_motor);
            app.FmaxLabel.Text = 'Fmax =';
            app.lm_hill_motor_Fmax = uieditfield(app.lm_hill_muscle_motor, 'numeric');
            app.lm_hill_motor_Fmax.Limits = [0 Inf];
            app.lm_hill_motor_Fmax.Tooltip = {''};
            app.VmaxLabel = uilabel(app.lm_hill_muscle_motor);
            app.VmaxLabel.Text = 'Vmax =';
            app.lm_hill_motor_Vmax = uieditfield(app.lm_hill_muscle_motor, 'numeric');
            app.lm_hill_motor_Vmax.Limits = [0 Inf];
            app.lm_hill_motor_Vmax.Tooltip = {''};
            app.musclelengthLabel = uilabel(app.lm_hill_muscle_motor);
            app.musclelengthLabel.Text = 'muscle length =';
            app.lm_hill_motor_muscle_length = uieditfield(app.lm_hill_muscle_motor, 'numeric');
            app.lm_hill_motor_muscle_length.Limits = [0 Inf];
            app.lm_hill_motor_muscle_length.ValueChangedFcn = createCallbackFcn(app, @lm_hill_motor_muscle_lengthValueChanged, true);
            app.lm_hill_motor_muscle_length.Tooltip = {''};
            app.RequiredFieldsLabel_4 = uilabel(app.lm_hill_muscle_motor);
            app.RequiredFieldsLabel_4.Text = 'Required Fields:';
            app.OptionalFieldsLabel_4 = uilabel(app.lm_hill_muscle_motor);
            app.OptionalFieldsLabel_4.Text = 'Optional Fields:';
            app.defaultvaluesfilledinLabel_4 = uilabel(app.lm_hill_muscle_motor);
            app.defaultvaluesfilledinLabel_4.Text = '(default values filled in)';
            app.rateofactivationLabel = uilabel(app.lm_hill_muscle_motor);
            app.rateofactivationLabel.Text = 'rate of activation =';
            app.lm_hill_motor_rate_of_activation = uieditfield(app.lm_hill_muscle_motor, 'numeric');
            app.lm_hill_motor_rate_of_activation.Limits = [0 Inf];
            app.lm_hill_motor_rate_of_activation.Tooltip = {''};
            app.initiallengthLabel = uilabel(app.lm_hill_muscle_motor);
            app.initiallengthLabel.Text = 'initial length =';
            app.lm_hill_motor_L_i = uieditfield(app.lm_hill_muscle_motor, 'numeric');
            app.lm_hill_motor_L_i.Tooltip = {''};
            app.a_LLabel = uilabel(app.lm_hill_muscle_motor);
            app.a_LLabel.Text = 'a_L =';
            app.lm_hill_motor_a_L = uieditfield(app.lm_hill_muscle_motor, 'numeric');
            app.lm_hill_motor_a_L.Tooltip = {''};
            app.lm_hill_motor_a_L.Value = 2.08;
            app.b_LLabel = uilabel(app.lm_hill_muscle_motor);
            app.b_LLabel.Text = 'b_L =';
            app.lm_hill_motor_b_L = uieditfield(app.lm_hill_muscle_motor, 'numeric');
            app.lm_hill_motor_b_L.Tooltip = {''};
            app.lm_hill_motor_b_L.Value = -2.89;
            app.sLabel = uilabel(app.lm_hill_muscle_motor);
            app.sLabel.Text = 's =';
            app.lm_hill_motor_s = uieditfield(app.lm_hill_muscle_motor, 'numeric');
            app.lm_hill_motor_s.Tooltip = {''};
            app.lm_hill_motor_s.Value = -0.75;
            app.latch = uitabgroup(app.UIFigure);
            app.rounded_latch = uitab(app.latch);
            app.rounded_latch.Title = 'Rounded Latch';
            app.RequiredFieldsLabel_5 = uilabel(app.rounded_latch);
            app.RequiredFieldsLabel_5.Text = 'Required Fields:';
            app.OptionalFieldsLabel_5 = uilabel(app.rounded_latch);
            app.OptionalFieldsLabel_5.Text = 'Optional Fields:';
            app.defaultvaluesfilledinLabel_5 = uilabel(app.rounded_latch);
            app.defaultvaluesfilledinLabel_5.Text = '(default values filled in)';
            app.massEditFieldLabel_2 = uilabel(app.rounded_latch);
            app.massEditFieldLabel_2.Text = 'mass =';
            app.latch_mass = uieditfield(app.rounded_latch, 'numeric');
            app.latch_mass.Limits = [0 Inf];
            app.latch_mass.Tooltip = {''};
            app.latch_mass.Value = 5;
            app.Label = uilabel(app.rounded_latch);
            app.Label.Text = 'μ =';
            app.latch_coeff_fric = uieditfield(app.rounded_latch, 'numeric');
            app.latch_coeff_fric.Limits = [0 Inf];
            app.latch_coeff_fric.Tooltip = {''};
            app.radiusLabel = uilabel(app.rounded_latch);
            app.radiusLabel.Text = 'radius =';
            app.latch_radius = uieditfield(app.rounded_latch, 'numeric');
            app.latch_radius.Limits = [0 Inf];
            app.latch_radius.Tooltip = {''};
            app.latch_radius.Value = 5;
            app.v_0Label = uilabel(app.rounded_latch);
            app.v_0Label.Text = 'v_0 =';
            app.latch_v_0 = uieditfield(app.rounded_latch, 'numeric');
            app.latch_v_0.Limits = [0 Inf];
            app.latch_v_0.Tooltip = {''};
            app.load = uitabgroup(app.UIFigure);
            app.load_mass = uitab(app.load);
            app.load_mass.Title = 'Load Mass';
            app.RequiredFieldsLabel_6 = uilabel(app.load_mass);
            app.RequiredFieldsLabel_6.Text = 'Required Fields:';
            app.massEditFieldLabel = uilabel(app.load_mass);
            app.massEditFieldLabel.Text = 'mass =';
            app.load_mass_mass = uieditfield(app.load_mass, 'numeric');
            app.load_mass_mass.Limits = [0 Inf];
            app.load_mass_mass.Tooltip = {''};
            app.load_mass_mass.Value = 5;
            app.LoadingMotorLabel = uilabel(app.UIFigure);
            app.LoadingMotorLabel.Text = 'Loading Motor:';
            app.unlatching_motor = uitabgroup(app.UIFigure);
            app.um_linear_motor = uitab(app.unlatching_motor);
            app.um_linear_motor.Title = 'Linear Motor';
            app.FmaxLabel_5 = uilabel(app.um_linear_motor);
            app.FmaxLabel_5.Text = 'Fmax =';
            app.um_linear_motor_Fmax = uieditfield(app.um_linear_motor, 'numeric');
            app.um_linear_motor_Fmax.Limits = [0 Inf];
            app.um_linear_motor_Fmax.Tooltip = {''};
            app.um_linear_motor_Fmax.Value = 10;
            app.VmaxLabel_3 = uilabel(app.um_linear_motor);
            app.VmaxLabel_3.Text = 'Vmax =';
            app.um_linear_motor_Vmax = uieditfield(app.um_linear_motor, 'numeric');
            app.um_linear_motor_Vmax.Limits = [0 Inf];
            app.um_linear_motor_Vmax.Tooltip = {''};
            app.um_linear_motor_Vmax.Value = 10;
            app.RequiredFieldsLabel_7 = uilabel(app.um_linear_motor);
            app.RequiredFieldsLabel_7.Text = 'Required Fields:';
            app.rangeofmotionLabel_2 = uilabel(app.um_linear_motor);
            app.rangeofmotionLabel_2.Text = 'range of motion =';
            app.um_linear_motor_range_of_motion = uieditfield(app.um_linear_motor, 'numeric');
            app.um_linear_motor_range_of_motion.Limits = [0 Inf];
            app.um_linear_motor_range_of_motion.Tooltip = {''};
            app.um_linear_motor_range_of_motion.Value = 2;
            app.um_hill_muscle_motor = uitab(app.unlatching_motor);
            app.um_hill_muscle_motor.Title = 'Hill Muscle Motor';
            app.FmaxLabel_6 = uilabel(app.um_hill_muscle_motor);
            app.FmaxLabel_6.Text = 'Fmax =';
            app.um_hill_motor_Fmax = uieditfield(app.um_hill_muscle_motor, 'numeric');
            app.um_hill_motor_Fmax.Limits = [0 Inf];
            app.um_hill_motor_Fmax.Tooltip = {''};
            app.VmaxLabel_4 = uilabel(app.um_hill_muscle_motor);
            app.VmaxLabel_4.Text = 'Vmax =';
            app.um_hill_motor_Vmax = uieditfield(app.um_hill_muscle_motor, 'numeric');
            app.um_hill_motor_Vmax.Limits = [0 Inf];
            app.um_hill_motor_Vmax.Tooltip = {''};
            app.musclelengthLabel_2 = uilabel(app.um_hill_muscle_motor);
            app.musclelengthLabel_2.Text = 'muscle length =';
            app.um_hill_motor_muscle_length = uieditfield(app.um_hill_muscle_motor, 'numeric');
            app.um_hill_motor_muscle_length.Limits = [0 Inf];
            app.um_hill_motor_muscle_length.ValueChangedFcn = createCallbackFcn(app, @um_hill_motor_muscle_lengthValueChanged, true);
            app.um_hill_motor_muscle_length.Tooltip = {''};
            app.RequiredFieldsLabel_8 = uilabel(app.um_hill_muscle_motor);
            app.RequiredFieldsLabel_8.Text = 'Required Fields:';
            app.OptionalFieldsLabel_6 = uilabel(app.um_hill_muscle_motor);
            app.OptionalFieldsLabel_6.Text = 'Optional Fields:';
            app.defaultvaluesfilledinLabel_6 = uilabel(app.um_hill_muscle_motor);
            app.defaultvaluesfilledinLabel_6.Text = '(default values filled in)';
            app.rateofactivationLabel_2 = uilabel(app.um_hill_muscle_motor);
            app.rateofactivationLabel_2.Text = 'rate of activation =';
            app.um_hill_motor_rate_of_activation = uieditfield(app.um_hill_muscle_motor, 'numeric');
            app.um_hill_motor_rate_of_activation.Limits = [0 Inf];
            app.um_hill_motor_rate_of_activation.Tooltip = {''};
            app.initiallengthLabel_2 = uilabel(app.um_hill_muscle_motor);
            app.initiallengthLabel_2.Text = 'initial length =';
            app.um_hill_motor_L_i = uieditfield(app.um_hill_muscle_motor, 'numeric');
            app.um_hill_motor_L_i.Tooltip = {''};
            app.a_LLabel_2 = uilabel(app.um_hill_muscle_motor);
            app.a_LLabel_2.Text = 'a_L =';
            app.um_hill_motor_a_L = uieditfield(app.um_hill_muscle_motor, 'numeric');
            app.um_hill_motor_a_L.Tooltip = {''};
            app.um_hill_motor_a_L.Value = 2.08;
            app.b_LLabel_2 = uilabel(app.um_hill_muscle_motor);
            app.b_LLabel_2.Text = 'b_L =';
            app.um_hill_motor_b_L = uieditfield(app.um_hill_muscle_motor, 'numeric');
            app.um_hill_motor_b_L.Tooltip = {''};
            app.um_hill_motor_b_L.Value = -2.89;
            app.sLabel_2 = uilabel(app.um_hill_muscle_motor);
            app.sLabel_2.Text = 's =';
            app.um_hill_motor_s = uieditfield(app.um_hill_muscle_motor, 'numeric');
            app.um_hill_motor_s.Tooltip = {''};
            app.um_hill_motor_s.Value = -0.75;
            app.UnlatchingMotorLabel = uilabel(app.UIFigure);
            app.UnlatchingMotorLabel.Text = 'Unlatching Motor:';
            app.IV1DropDownLabel = uilabel(app.UIFigure);
            app.IV1DropDownLabel.Text = 'IV 1';
            app.IV1DropDown = uidropdown(app.UIFigure);
            app.IV1DropDown.Items = {'--no selection--', 'load_mass_mass', 'latch_mass', ''};
            app.IV1DropDown.ValueChangedFcn = createCallbackFcn(app, @IV1DropDownValueChanged, true);
            app.IV1DropDown.Value = '--no selection--';
            app.IV2DropDownLabel = uilabel(app.UIFigure);
            app.IV2DropDownLabel.Text = 'IV 2';
            app.IV2DropDown = uidropdown(app.UIFigure);
            app.IV2DropDown.Items = {'--no selection--', 'load_mass_mass', 'latch_mass'};
            app.IV2DropDown.Value = '--no selection--';
            app.xminEditFieldLabel = uilabel(app.UIFigure);
            app.xminEditFieldLabel.Text = 'x min';
            app.xmin = uieditfield(app.UIFigure, 'numeric');
            app.xmin.Value = -1;
            app.xmaxEditFieldLabel = uilabel(app.UIFigure);
            app.xmaxEditFieldLabel.Text = 'x max';
            app.xmax = uieditfield(app.UIFigure, 'numeric');
            app.xmax.Value = 3;
            app.xminEditFieldLabel_2 = uilabel(app.UIFigure);
            app.xminEditFieldLabel_2.Text = 'x min';
            app.ymin = uieditfield(app.UIFigure, 'numeric');
            app.ymin.Value = -2;
            app.xmaxEditFieldLabel_2 = uilabel(app.UIFigure);
            app.xmaxEditFieldLabel_2.Text = 'x max';
            app.ymax = uieditfield(app.UIFigure, 'numeric');
            app.ymax.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = apptrial1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end