classdef practiceApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        savesolutionCheckBox          matlab.ui.control.CheckBox
        GraphButton                   matlab.ui.control.Button
        GraphingCornerLabel           matlab.ui.control.Label
        GraphingCorner                matlab.ui.container.TabGroup
        KinematicsTab                 matlab.ui.container.Tab
        KinematicsOutputOptionsLabel  matlab.ui.control.Label
        UnlatchingandlaunchingphaseLabel  matlab.ui.control.Label
        LoadingphaseLabel             matlab.ui.control.Label
        loadkinematicsCheckBox_2      matlab.ui.control.CheckBox
        latchkinematicsCheckBox_2     matlab.ui.control.CheckBox
        motorandspringforcedisplacementcurvesCheckBox_2  matlab.ui.control.CheckBox
        one_D                         matlab.ui.container.Tab
        OD_xmax                       matlab.ui.control.NumericEditField
        OD_xmin                       matlab.ui.control.NumericEditField
        xminEditFieldLabel            matlab.ui.control.Label
        ComparetoDirectActuationCheckBox  matlab.ui.control.CheckBox
        OD_x_log_space                matlab.ui.control.Switch
        xmaxEditFieldLabel            matlab.ui.control.Label
        OD_n                          matlab.ui.control.NumericEditField
        pixelsofresolutionLabel       matlab.ui.control.Label
        yaxisOutputOptionsLabel       matlab.ui.control.Label
        OD_IV1DropDown                matlab.ui.control.DropDown
        OD_KE_maxCheckBox             matlab.ui.control.CheckBox
        OD_t_toCheckBox               matlab.ui.control.CheckBox
        OD_P_maxCheckBox              matlab.ui.control.CheckBox
        OD_v_toCheckBox               matlab.ui.control.CheckBox
        OD_t_LCheckBox                matlab.ui.control.CheckBox
        OD_y_unlatchCheckBox          matlab.ui.control.CheckBox
        OD_y_maxCheckBox              matlab.ui.control.CheckBox
        xaxisLabel                    matlab.ui.control.Label
        OD_a_maxCheckBox              matlab.ui.control.CheckBox
        heatmap                       matlab.ui.container.Tab
        MakeLaMSAZonePlotCheckBox     matlab.ui.control.CheckBox
        y_log_space                   matlab.ui.control.Switch
        x_log_space                   matlab.ui.control.Switch
        n                             matlab.ui.control.NumericEditField
        pixelsofresolutionLabel_2     matlab.ui.control.Label
        HeatmapOutputOptionsLabel     matlab.ui.control.Label
        ymax                          matlab.ui.control.NumericEditField
        ymaxLabel                     matlab.ui.control.Label
        ymin                          matlab.ui.control.NumericEditField
        yminLabel                     matlab.ui.control.Label
        xmax                          matlab.ui.control.NumericEditField
        xmaxEditFieldLabel_2          matlab.ui.control.Label
        xmin                          matlab.ui.control.NumericEditField
        xminEditFieldLabel_2          matlab.ui.control.Label
        IV2DropDown                   matlab.ui.control.DropDown
        IV1DropDown                   matlab.ui.control.DropDown
        KE_maxCheckBox                matlab.ui.control.CheckBox
        t_toCheckBox                  matlab.ui.control.CheckBox
        P_maxCheckBox                 matlab.ui.control.CheckBox
        v_toCheckBox                  matlab.ui.control.CheckBox
        t_LCheckBox                   matlab.ui.control.CheckBox
        y_unlatchCheckBox             matlab.ui.control.CheckBox
        y_maxCheckBox                 matlab.ui.control.CheckBox
        yaxisLabel                    matlab.ui.control.Label
        xaxisLabel_2                  matlab.ui.control.Label
        a_maxCheckBox                 matlab.ui.control.CheckBox
        LoadingMotorLabel             matlab.ui.control.Label
        UnlatchingMotorLabel          matlab.ui.control.Label
        ParametersLabel               matlab.ui.control.Label
        MassTabGroup                  matlab.ui.container.TabGroup
        Tab4                          matlab.ui.container.Tab
        LatchTabGroup                 matlab.ui.container.TabGroup
        Tab3                          matlab.ui.container.Tab
        UnlatchingMotorTabGroup       matlab.ui.container.TabGroup
        Tab2                          matlab.ui.container.Tab
        LoadingMotorTabGroup          matlab.ui.container.TabGroup
        Tab1                          matlab.ui.container.Tab
        GoButton                      matlab.ui.control.Button
        SpringTabGroup                matlab.ui.container.TabGroup
        Tab                           matlab.ui.container.Tab
    end

    
    properties (Access = public)
        SpringTabs        = matlab.ui.container.Tab
        SpringEditFields  = matlab.ui.control.EditField
        SpringLabels      = matlab.ui.control.Label
        
        LoadingMotorTabs        = matlab.ui.container.Tab
        LoadingMotorEditFields  = matlab.ui.control.EditField
        LoadingMotorLabels      = matlab.ui.control.Label
        
        UnlatchingMotorTabs        = matlab.ui.container.Tab
        UnlatchingMotorEditFields  = matlab.ui.control.EditField
        UnlatchingMotorLabels      = matlab.ui.control.Label
        
        MassTabs        = matlab.ui.container.Tab
        MassEditFields  = matlab.ui.control.EditField
        MassLabels      = matlab.ui.control.Label
        
        LatchTabs        = matlab.ui.container.Tab
        LatchEditFields  = matlab.ui.control.EditField
        LatchLabels      = matlab.ui.control.Label
        
        SpringParamMap = containers.Map;
        SpringParamIndices = containers.Map;
        
        LoadingMotorParamMap = containers.Map;
        LoadingMotorParamIndices = containers.Map;
        
        UnlatchingMotorParamMap = containers.Map;
        UnlatchingMotorParamIndices = containers.Map;
        
        MassParamMap = containers.Map;
        MassParamIndices = containers.Map;
        
        LatchParamMap = containers.Map;
        LatchParamIndices = containers.Map;
    end
    
    properties (Access = private)
        
        DDvars = [];
        
        load_vars;
        latch_vars;
        spring_vars;
        lm_vars;
        um_vars;
        dd_vars;
        %dd_vars_labels;
        %axis_labels_dict;
        dropdown_items_dict;
        dropdown_items_opposite_dict;
    end
    
    methods (Access = private)
        
        function update_dd_vars(app)
            app.dd_vars = [app.load_vars app.latch_vars app.spring_vars app.lm_vars app.um_vars];
%             for i=1:length(app.dd_vars)
%                 app.dd_vars_labels{i} = app.dropdown_items_dict(app.dd_vars{i});
%             end
            app.IV1DropDown.Items = app.dd_vars;
            app.IV2DropDown.Items = app.dd_vars;
            app.OD_IV1DropDown.Items = app.dd_vars;
            %app.sensitivity_vars.Items = app.dd_vars;
        end
        
        % retrieve value from editfield given dropdown selection and
        % editfield index
        function val = getEditFieldVal(app, name, index)
            if startsWith(name, "load")
                val = app.MassEditFields(index).Value;
            elseif startsWith(name, "latch")
                val = app.LatchEditFields(index).Value;
            elseif startsWith(name, "spring")
                val = app.SpringEditFields(index).Value;
            elseif startsWith(name, "loading motor")
                val = app.LoadingMotorEditFields(index).Value;
            elseif startsWith(name, "unlatching motor")
                val = app.UnlatchingMotorEditFields(index).Value;
            end
        end
        
        % modify the value of an editfield given dropdown selection,
        % editfield index, and a new value
        function changeEditField(app, name, index, newVal)
            if startsWith(name, "load")
                app.MassEditFields(index).Value = newVal;
            elseif startsWith(name, "latch")
                app.LatchEditFields(index).Value = newVal;
            elseif startsWith(name, "spring")
                app.SpringEditFields(index).Value = newVal;
            elseif startsWith(name, "loading motor")
                app.LoadingMotorEditFields(index).Value = newVal;
            elseif startsWith(name, "unlatching motor")
                app.UnlatchingMotorEditFields(index).Value = newVal;
            end
        end
        
        % gnarly indexing to figure out the editfield index from
        % the dropdown index
        function efIndex = ddIndexToefIndex(app, ddIndex, param)
            efIndex = ddIndex;
            
            is_loadingMotor = false;
            if startsWith(param, "load")
                object = app.MassTabGroup.SelectedTab.Title;
            elseif startsWith(param, "latch")
                object = app.LatchTabGroup.SelectedTab.Title;
            elseif startsWith(param, "spring")
                object = app.SpringTabGroup.SelectedTab.Title;
            elseif startsWith(param, "loading motor")
                object = app.LoadingMotorTabGroup.SelectedTab.Title;
                is_loadingMotor = true;
            elseif startsWith(param, "unlatching motor")
                object = app.UnlatchingMotorTabGroup.SelectedTab.Title;
            end
            
            loadObjects = app.MassTabGroup.Children;
            for ii = 1:length(loadObjects)
                name = loadObjects(ii).Title;
                if strcmp(name, object)
                    return
                end
                if ~strcmp(name, app.MassTabGroup.SelectedTab.Title)
                    index = app.MassParamIndices(name);
                    efIndex = efIndex + (index(2)-index(1)+1);
                end
            end
            
            latchObjects = app.LatchTabGroup.Children;
            for ii = 1:length(latchObjects)
                name = latchObjects(ii).Title;
                if strcmp(name, object)
                    return
                end
                if ~strcmp(name, app.LatchTabGroup.SelectedTab.Title)
                    index = app.LatchParamIndices(name);
                    efIndex = efIndex + (index(2)-index(1)+1);
                end
            end
            
            springObjects = app.SpringTabGroup.Children;
            for ii = 1:length(springObjects)
                name = springObjects(ii).Title;
                if strcmp(name, object)
                    return
                end
                if ~strcmp(name, app.SpringTabGroup.SelectedTab.Title)
                    index = app.SpringParamIndices(name);
                    efIndex = efIndex + (index(2)-index(1)+1);
                end
            end
            
            lmObjects = app.LoadingMotorTabGroup.Children;
            for ii = 1:length(lmObjects)
                name = lmObjects(ii).Title;
                if strcmp(name, object) && is_loadingMotor
                    return
                end
                if ~strcmp(name, app.LoadingMotorTabGroup.SelectedTab.Title)
                    index = app.LoadingMotorParamIndices(name);
                    efIndex = efIndex + (index(2)-index(1)+1);
                end
            end
            
            umObjects = app.UnlatchingMotorTabGroup.Children;
            for ii = 1:length(umObjects)
                name = umObjects(ii).Title;
                if strcmp(name, object)
                    return
                end
                if ~strcmp(name, app.UnlatchingMotorTabGroup.SelectedTab.Title)
                    index = app.UnlatchingMotorParamIndices(name);
                    efIndex = efIndex + (index(2)-index(1)+1);
                end
            end
            
        end
        
        function one_D_plot(app)
            if ~(app.OD_y_maxCheckBox.Value || app.OD_y_unlatchCheckBox.Value || app.OD_t_LCheckBox.Value || ...
                    app.OD_v_toCheckBox.Value || app.OD_P_maxCheckBox.Value || app.OD_t_toCheckBox.Value || app.OD_KE_maxCheckBox.Value)
                warndlg('You must select at least one output option', 'Warning');
                return
            end
            if strcmp(app.OD_x_log_space.Value,'log')
                if ((app.OD_xmin.Value <= 0) |(app.OD_xmax.Value <= 0))
                    warndlg("One or both of the values you've entered for the xmin/xmax " + ...
                        "looping range is non-positive, " + ...
                        "AND you have chosen a logspace x-axis. " + newline +...
                        "Either change to linear spacing or use a positive bound.", 'Warning')
                    return
                end
            end
            
            % determines resolution of heatplots
            N=app.OD_n.Value; 
            
            % output directory initialization
            if app.savesolutionCheckBox.Value
                output_directory = create_output_directory();
            end
            
            %% plot parameters
            looping_param_x = app.OD_IV1DropDown.Value;
            
            [~, index] = ismember(app.OD_IV1DropDown.Value, app.OD_IV1DropDown.Items);
            efIndex = ddIndexToefIndex(app, index, looping_param_x);
            
            looping_param_x_value = getEditFieldVal(app, looping_param_x, efIndex);
%             eval(['looping_param_x_value = app.' app.OD_IV1DropDown.Value '.Value;']);
%             eval(['looping_param_x_limits = app.' app.OD_IV1DropDown.Value '.Limits;']);
%             
%             if ((app.OD_xmin.Value < looping_param_x_limits(1)) | ...
%                 (app.OD_xmin.Value > looping_param_x_limits(2)) | ...
%                 (app.OD_xmax.Value < looping_param_x_limits(1)) | ...
%                 (app.OD_xmax.Value > looping_param_x_limits(2)))     
%                 warndlg("You have chosen an xmin or xmax value that is out of the bounds for the looping quantity you have selected." + newline + ...
%                     "i.e. you have chosen to loop through negative masses, or negative coefficients of friction, or something similarly bad.", ...
%                     'Warning')
%                 return
%             end
            
            % initializing waitbar
            f = waitbar(0,'Please wait...');
            load_bar_value = 1/N;
            load_bar_increment = 1/N;
            
            % setting x axis on the plot
            xname = app.OD_IV1DropDown.Value;
            if strcmp(app.OD_x_log_space.Value,'log')
                xrange = [log10(app.OD_xmin.Value) log10(app.OD_xmax.Value)];
                looping_value_x = logspace(xrange(1),xrange(2),N);
            else
                xrange = [app.OD_xmin.Value app.OD_xmax.Value];
                looping_value_x = linspace(xrange(1),xrange(2),N);
            end
            
            % add things to metrics
            metrics = {};
            
            if app.OD_y_maxCheckBox.Value
                metrics{end+1} = 'ymax';
            end
            if app.OD_y_unlatchCheckBox.Value
                metrics{end+1} = 'yunlatch';
            end
            if app.OD_t_LCheckBox.Value
                metrics{end+1} = 'tL';
            end
            if app.OD_v_toCheckBox.Value
                metrics{end+1} = 'vto';
            end
            if app.OD_P_maxCheckBox.Value
                metrics{end+1} = 'Pmax';
            end
            if app.OD_t_toCheckBox.Value
                metrics{end+1} = 'tto';
            end
            if app.OD_a_maxCheckBox.Value
                metrics{end+1} = 'amax';
            end
            if app.OD_KE_maxCheckBox.Value
                metrics{end+1} = 'KEmax';
            end
            
            if isempty(metrics)
                warndlg('You must pick at least one output option','Error');
                return
            end
            
            metrics_names = {'ymax','yunlatch','tL','vto','Pmax','tto','amax','KEmax', 'KE_Ratio', 'unlatching_motor_work_done'};
            metrics_labels = {'$y_{\textrm{max}}$','$y_{\textrm{unlatch}}$','$t_L$','$v_{\textrm{to}}$','$P_{\textrm{max}}$','$t_{\textrm{to}}$','$a_\textrm{max}$','$KE_{\textrm{max}}$', 'KE Ratio', 'unlatching motor work done'};
            metrics_dict = containers.Map(metrics_names,metrics_labels);
            
            for ii=1:length(metrics)
                outval{ii} = zeros(size(looping_value_x));
            end
            for i=1:N
                
                % update looping value
                changeEditField(app, looping_param_x, efIndex, string(looping_value_x(i)));
                
                %% initializing LaMSA component structs
                
                % coupling for mass used in the paper: app.load_mass_mass.Value = (app.lm_hill_motor_Fmax.Value/86.2)^1.5;
                spring_name = app.SpringTabGroup.SelectedTab.Title;
                latch_name = app.LatchTabGroup.SelectedTab.Title;
                mass_name = app.MassTabGroup.SelectedTab.Title;
                loading_motor_name = app.LoadingMotorTabGroup.SelectedTab.Title;
                unlatching_motor_name = app.UnlatchingMotorTabGroup.SelectedTab.Title;
                
                spring = initialize_component(spring_name, app.SpringParamIndices, app.SpringEditFields);
                loading_motor = initialize_component(loading_motor_name, app.LoadingMotorParamIndices, app.LoadingMotorEditFields);
                unlatching_motor = initialize_component(unlatching_motor_name, app.UnlatchingMotorParamIndices, app.UnlatchingMotorEditFields);
                load = initialize_component(mass_name, app.MassParamIndices, app.MassEditFields);
                latch = initialize_component(latch_name, app.LatchParamIndices, app.LatchEditFields);
                
                
                % calling solve model
                if app.savesolutionCheckBox.Value
                    [sol,transition_times] = solve_model(loading_motor,unlatching_motor,load,latch,spring,output_directory);
                else
                    [sol,transition_times] = solve_model(loading_motor,unlatching_motor,load,latch,spring);
                end
                
                % warning messages
                [warnMsg, warnId] = lastwarn;
                if (strcmp(warnMsg,'Loading failed. Does not fall within latching distance conditions.'))
                    if ~exist('loading_failed','var')
                        loading_failed = warndlg('Loading failed. Does not fall within latching distance conditions.','Warning');
                    end
                end
                if (strcmp(warnMsg,'Latch gets stuck!'))
                    if ~exist('latch_stuck','var')
                        latch_stuck = warndlg('Latch gets stuck!','Warning');
                    end
                end
                if (strcmp(warnMsg,"The latch's initial velocity and acceleration are both zero."))
                    if ~exist('latch_vi_a_zero','var')
                        latch_vi_a_zero = warndlg("The latch's initial velocity and acceleration are both zero.",'Warning');
                    end
                end
                
                met_dict=get_metrics(sol,transition_times,load,metrics);
                for ii=1:length(metrics)
                    outval{ii}(i)=met_dict(metrics{ii});
                end
                
                % Run direct actuation model if ComparetoDirectActuationCheckBox
                % is selected
                if (app.ComparetoDirectActuationCheckBox.Value)
                    [direct_sol, direct_transition_times] = solve_direct_actuation(loading_motor,load);
                    direct_met_dict=get_metrics(direct_sol,direct_transition_times,load,metrics);
                    for ii=1:length(metrics)
                        direct_outval{ii}(i)=direct_met_dict(metrics{ii});
                    end
                end
                
                disp(['point ' num2str(i) ' of ' num2str(N)]);
                load_bar_value = load_bar_value + load_bar_increment;
                waitbar(load_bar_value,f,'Processing...');
            end

            close(f);

            changeEditField(app, looping_param_x, efIndex, looping_param_x_value);
            
            % plot output
            fh = figure('Name','1D Plot');
            fh.WindowState = 'maximized';
            subplot_rows = floor(sqrt(length(metrics)));
            subplot_cols = ceil(length(metrics)/floor(sqrt(length(metrics))));
            for ii=1:length(metrics)
                subplot(subplot_rows,subplot_cols,ii);
                plot(looping_value_x,outval{ii},'-b'); 
                if (app.ComparetoDirectActuationCheckBox.Value)
                    hold on;
                    plot(looping_value_x,direct_outval{ii},'-r');
                    legend({'LaMSA','Direct Actuation'},'Location','Best');
                end
                %xlabel(app.axis_labels_dict(xname),'Interpreter', 'Latex');
                xlabel(xname, 'Interpreter', 'Latex');
                ylabel(metrics_dict(metrics{ii}), 'Interpreter', 'Latex');
                ylim_range = max(outval{ii})-min(outval{ii});
                if (ylim_range == 0)
                    ylim_range = abs(max(outval{ii}));
                    if ~(ylim_range == 0)
                        ylim([min([0, min(outval{ii})-0.05*ylim_range]) max([0,max(outval{ii})+0.05*ylim_range]) ])
                    end
                else
                    ylim([min(outval{ii})-0.05*ylim_range max(outval{ii})+0.05*ylim_range])
                end
                set(gca,'TickLabelInterpreter','latex')
                % makes the x in log scale if needed
                if strcmp(app.OD_x_log_space.Value,'log')
                    set(gca,'XScale','log')
                end
               
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Constants for formatting tabs
            yPos = 15;
            xPos = 140;
            colSpacing = 250;
            labelSpacing = 120;
            width = 80;
            height = 22;
            
            %%%%%%%%%%%%%%  LOAD MASS TABGROUP INITIALIZATION  %%%%%%%%%%%%
            
            massFiles = dir('load-masses/Subclasses/*.m');
            delete(get(app.MassTabGroup, 'Children'));

            ind = 1;
            for file = massFiles'
                [~, name, ~] = fileparts(file.name);
                tab = uitab(app.MassTabGroup, 'Title', name);
                params = feval([name '.parameters']);
                app.MassParamIndices(name) = [ind, ind + length(params) - 1];
                app.MassParamMap(name) = params;
                for ii = 1:length(params)
                    paramName = params(1,ii);
                    defaultVal = params(2,ii);
                    col = mod(ii-1, 3);
                    row = floor((ii-1)/3);
                    app.MassEditFields(ind) = uieditfield(tab);
                    app.MassEditFields(ind).Position = [xPos+colSpacing*col yPos+row*25 width height];
                    app.MassEditFields(ind).Value = defaultVal;
                    app.MassLabels(ind) = uilabel(tab);
                    app.MassLabels(ind).Text = paramName;
                    app.MassLabels(ind).Position = [xPos+colSpacing*col-labelSpacing yPos+row*25 150 height];
                    ind = ind + 1;
                end
                app.load_vars = "load " + params(1,:);
            end
            
            %%%%%%%%%%%%%%  LATCH TABGROUP INITIALIZATION  %%%%%%%%%%%%%%%%
            
            latchFiles = dir('latches/Subclasses/*.m');
            delete(get(app.LatchTabGroup, 'Children'));

            for file = latchFiles'
                [~, name, ~] = fileparts(file.name);
                tab = uitab(app.LatchTabGroup, 'Title', name);
                params = feval([name '.parameters']);
                app.LatchParamIndices(name) = [ind, ind + length(params) - 1];
                app.LatchParamMap(name) = params;
                for ii = 1:length(params)
                    paramName = params(1,ii);
                    defaultVal = params(2,ii);
                    col = mod(ii-1, 3);
                    row = floor((ii-1)/3);
                    app.LatchEditFields(ind) = uieditfield(tab);
                    app.LatchEditFields(ind).Position = [xPos+colSpacing*col yPos+row*25 width height];
                    app.LatchEditFields(ind).Value = defaultVal;
                    app.LatchLabels(ind) = uilabel(tab);
                    app.LatchLabels(ind).Text = paramName;
                    app.LatchLabels(ind).Position = [xPos+colSpacing*col-labelSpacing yPos+row*25 150 height];
                    ind = ind + 1;
                end
                app.latch_vars = "latch " + params(1,:);
            end
            
            %%%%%%%%%%%%%%  SPRING TABGROUP INITIALIZATION  %%%%%%%%%%%%%
            
            springFiles = dir('springs/Subclasses/*.m');
            delete(get(app.SpringTabGroup, 'Children'));

            for file = springFiles'
                [~, name, ~] = fileparts(file.name);
                tab = uitab(app.SpringTabGroup, 'Title', name);
                params = feval([name '.parameters']);
                app.SpringParamIndices(name) = [ind, ind + length(params) - 1];
                app.SpringParamMap(name) = params;
                for ii = 1:length(params)
                    paramName = params(1,ii);
                    defaultVal = params(2,ii);
                    col = mod(ii-1, 3);
                    row = floor((ii-1)/3);
                    app.SpringEditFields(ind) = uieditfield(tab);
                    app.SpringEditFields(ind).Position = [xPos+colSpacing*col yPos+row*25 width height];
                    app.SpringEditFields(ind).Value = defaultVal;
                    app.SpringLabels(ind) = uilabel(tab);
                    app.SpringLabels(ind).Text = paramName;
                    app.SpringLabels(ind).Position = [xPos+colSpacing*col-labelSpacing yPos+row*25 150 height];
                    ind = ind + 1;
                end
                app.spring_vars = "spring " + params(1,:);
            end

            %%%%%%%%%%%%%%%%%%%%%%%  LOADING MOTOR TABGROUP INITIALIZATION  %%%%%%%%%%%%%%%%
            
            motorFiles = dir('motors/Subclasses/*.m');
            delete(get(app.LoadingMotorTabGroup, 'Children'));

            for file = motorFiles'
                [~, name, ~] = fileparts(file.name);
                loadTab = uitab(app.LoadingMotorTabGroup, 'Title', name);
                params = feval([name '.parameters']);
                app.LoadingMotorParamIndices(name) = [ind, ind + length(params) - 1];
                app.LoadingMotorParamMap(name) = params(1:2,:);
                for ii = 1:length(params)
                    paramName = params(1,ii);
                    loadDefaultVal = params(2,ii);
                    col = mod(ii-1, 3);
                    row = floor((ii-1)/3);
                    
                    app.LoadingMotorEditFields(ind) = uieditfield(loadTab);
                    app.LoadingMotorEditFields(ind).Position = [xPos+colSpacing*col yPos+row*25 width height];
                    app.LoadingMotorEditFields(ind).Value = loadDefaultVal;
                    app.LoadingMotorLabels(ind) = uilabel(loadTab);
                    app.LoadingMotorLabels(ind).Text = paramName;
                    app.LoadingMotorLabels(ind).Position = [xPos+colSpacing*col-labelSpacing yPos+row*25 150 height];
                    ind = ind + 1;
                end
                app.um_vars = "unlatching motor " + params(1,:);
                params = app.LoadingMotorParamMap(name);
                app.lm_vars = "loading motor " + params(1,:);
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%% UNLATCHING MOTOR INITIALIZATION %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            motorFiles = dir('motors/Subclasses/*.m');
            delete(get(app.UnlatchingMotorTabGroup, 'Children'));

            for file = motorFiles'
                [~, name, ~] = fileparts(file.name);
                tab = uitab(app.UnlatchingMotorTabGroup, 'Title', name);
                params = feval([name '.parameters']);
                app.UnlatchingMotorParamIndices(name) = [ind, ind + length(params) - 1];
                app.UnlatchingMotorParamMap(name) = [params(1,:);params(3,:)];
                for ii = 1:length(params)
                    paramName = params(1,ii);
                    defaultVal = params(3,ii);
                    col = mod(ii-1, 3);
                    row = floor((ii-1)/3);
                    app.UnlatchingMotorEditFields(ind) = uieditfield(tab);
                    app.UnlatchingMotorEditFields(ind).Position = [xPos+colSpacing*col yPos+row*25 width height];
                    app.UnlatchingMotorEditFields(ind).Value = defaultVal;
                    app.UnlatchingMotorLabels(ind) = uilabel(tab);
                    app.UnlatchingMotorLabels(ind).Text = paramName;
                    app.UnlatchingMotorLabels(ind).Position = [xPos+colSpacing*col-labelSpacing yPos+row*25 150 height];
                    ind = ind + 1;
                end
                app.um_vars = "unlatching motor " + params(1,:);
            end
            
            update_dd_vars(app);
        end

        % Selection change function: SpringTabGroup
        function SpringTabGroupSelectionChanged(app, event)
            selectedTab = app.SpringTabGroup.SelectedTab;
            params = app.SpringParamMap(selectedTab.Title);
            app.spring_vars = "spring " + params(1,:);
            update_dd_vars(app);
        end

        % Selection change function: LoadingMotorTabGroup
        function LoadingMotorTabGroupSelectionChanged(app, event)
            selectedTab = app.LoadingMotorTabGroup.SelectedTab;
            params = app.LoadingMotorParamMap(selectedTab.Title);
            app.lm_vars = "loading motor " + params(1,:);
            update_dd_vars(app);
        end

        % Selection change function: UnlatchingMotorTabGroup
        function UnlatchingMotorTabGroupSelectionChanged(app, event)
            selectedTab = app.UnlatchingMotorTabGroup.SelectedTab;
            params = app.UnlatchingMotorParamMap(selectedTab.Title);
            app.um_vars = "unlatching motor " + params(1,:);
            update_dd_vars(app);
        end

        % Selection change function: LatchTabGroup
        function LatchTabGroupSelectionChanged(app, event)
            selectedTab = app.LatchTabGroup.SelectedTab;
            params = app.LatchParamMap(selectedTab.Title);
            app.latch_vars = "latch " + params(1,:);
            update_dd_vars(app);
        end

        % Selection change function: MassTabGroup
        function MassTabGroupSelectionChanged(app, event)
            selectedTab = app.MassTabGroup.SelectedTab;
            params = app.MassParamMap(selectedTab.Title);
            app.load_vars = "load " + params(1,:);
            update_dd_vars(app);
        end

        % Button pushed function: GraphButton
        function GraphButtonPushed(app, event)
            lastwarn('');
            
%             if (app.GraphingCorner.SelectedTab == app.GraphingCorner_heatmap)
%                 heatmap(app);
            if (app.GraphingCorner.SelectedTab == app.one_D)
                one_D_plot(app);
%             elseif (app.GraphingCorner.SelectedTab == app.GraphingCorner_kinematics)
%                 kinematics(app);
%             elseif (app.GraphingCorner.SelectedTab == app.GraphingCorner_sensitivity)
%                 sensitivity(app);
            end
            
        end

        % Value changed function: IV1DropDown
        function IV1DropDownValueChanged(app, event)
            value = app.IV1DropDown.Value;
            app.IV2DropDown.Items = app.dd_vars;
            app.IV2DropDown.Items(ismember(app.IV2DropDown.Items,value)) = [];
        end

        % Value changed function: IV2DropDown
        function IV2DropDownValueChanged(app, event)
            value = app.IV2DropDown.Value;
            app.IV1DropDown.Items = app.dd_vars;
            app.IV1DropDown.Items(ismember(app.IV1DropDown.Items,value)) = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SpringTabGroup = uitabgroup(app.UIFigure);
            app.SpringTabGroup.SelectionChangedFcn = createCallbackFcn(app, @SpringTabGroupSelectionChanged, true);
            app.Tab = uitab(app.SpringTabGroup);
            app.Tab.Title = 'Spring Tabs';
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.Text = 'Go';
            app.LoadingMotorTabGroup = uitabgroup(app.UIFigure);
            app.LoadingMotorTabGroup.SelectionChangedFcn = createCallbackFcn(app, @LoadingMotorTabGroupSelectionChanged, true);
            app.Tab1 = uitab(app.LoadingMotorTabGroup);
            app.Tab1.Title = 'Loading Motor Tabs';
            app.UnlatchingMotorTabGroup = uitabgroup(app.UIFigure);
            app.UnlatchingMotorTabGroup.SelectionChangedFcn = createCallbackFcn(app, @UnlatchingMotorTabGroupSelectionChanged, true);
            app.Tab2 = uitab(app.UnlatchingMotorTabGroup);
            app.Tab2.Title = 'Unlatching Motor Tabs';
            app.LatchTabGroup = uitabgroup(app.UIFigure);
            app.LatchTabGroup.SelectionChangedFcn = createCallbackFcn(app, @LatchTabGroupSelectionChanged, true);
            app.Tab3 = uitab(app.LatchTabGroup);
            app.Tab3.Title = 'Latch Tabs';
            app.MassTabGroup = uitabgroup(app.UIFigure);
            app.MassTabGroup.SelectionChangedFcn = createCallbackFcn(app, @MassTabGroupSelectionChanged, true);
            app.Tab4 = uitab(app.MassTabGroup);
            app.Tab4.Title = 'Load Mass Tabs';
            app.ParametersLabel = uilabel(app.UIFigure);
            app.ParametersLabel.Text = 'Parameters';
            app.UnlatchingMotorLabel = uilabel(app.UIFigure);
            app.UnlatchingMotorLabel.Text = 'Unlatching Motor:';
            app.LoadingMotorLabel = uilabel(app.UIFigure);
            app.LoadingMotorLabel.Text = 'Loading Motor:';
            app.GraphingCorner = uitabgroup(app.UIFigure);
            app.KinematicsTab = uitab(app.GraphingCorner);
            app.KinematicsTab.Title = 'Kinematics';
            app.motorandspringforcedisplacementcurvesCheckBox_2 = uicheckbox(app.KinematicsTab);
            app.motorandspringforcedisplacementcurvesCheckBox_2.Text = 'motor and spring force displacement curves';
            app.latchkinematicsCheckBox_2 = uicheckbox(app.KinematicsTab);
            app.latchkinematicsCheckBox_2.Text = 'latch kinematics';
            app.loadkinematicsCheckBox_2 = uicheckbox(app.KinematicsTab);
            app.loadkinematicsCheckBox_2.Text = 'load kinematics';
            app.LoadingphaseLabel = uilabel(app.KinematicsTab);
            app.LoadingphaseLabel.Text = 'Loading phase';
            app.UnlatchingandlaunchingphaseLabel = uilabel(app.KinematicsTab);
            app.UnlatchingandlaunchingphaseLabel.Text = 'Unlatching and launching phase';
            app.KinematicsOutputOptionsLabel = uilabel(app.KinematicsTab);
            app.KinematicsOutputOptionsLabel.Text = 'Kinematics Output Options';
            app.one_D = uitab(app.GraphingCorner);
            app.one_D.Title = '1D Plot';
            app.OD_a_maxCheckBox = uicheckbox(app.one_D);
            app.OD_a_maxCheckBox.Text = 'a_max';
            app.xaxisLabel = uilabel(app.one_D);
            app.xaxisLabel.Text = 'x-axis';
            app.OD_y_maxCheckBox = uicheckbox(app.one_D);
            app.OD_y_maxCheckBox.Text = 'y_max';
            app.OD_y_unlatchCheckBox = uicheckbox(app.one_D);
            app.OD_y_unlatchCheckBox.Text = 'y_unlatch';
            app.OD_t_LCheckBox = uicheckbox(app.one_D);
            app.OD_t_LCheckBox.Text = 't_L';
            app.OD_v_toCheckBox = uicheckbox(app.one_D);
            app.OD_v_toCheckBox.Text = 'v_to';
            app.OD_P_maxCheckBox = uicheckbox(app.one_D);
            app.OD_P_maxCheckBox.Text = 'P_max';
            app.OD_P_maxCheckBox.Value = true;
            app.OD_t_toCheckBox = uicheckbox(app.one_D);
            app.OD_t_toCheckBox.Text = 't_to';
            app.OD_KE_maxCheckBox = uicheckbox(app.one_D);
            app.OD_KE_maxCheckBox.Text = 'KE_max';
            app.OD_IV1DropDown = uidropdown(app.one_D);
            app.OD_IV1DropDown.Items = {'--no selection--'};
            app.OD_IV1DropDown.Tooltip = {'Independent Variable 1'};
            app.OD_IV1DropDown.Value = '--no selection--';
            app.yaxisOutputOptionsLabel = uilabel(app.one_D);
            app.yaxisOutputOptionsLabel.Text = 'y-axis Output Options';
            app.pixelsofresolutionLabel = uilabel(app.one_D);
            app.pixelsofresolutionLabel.Text = 'pixels of resolution =';
            app.OD_n = uieditfield(app.one_D, 'numeric');
            app.OD_n.Limits = [1 1024];
            app.OD_n.RoundFractionalValues = 'on';
            app.OD_n.ValueDisplayFormat = '%.0f';
            app.OD_n.Value = 100;
            app.xmaxEditFieldLabel = uilabel(app.one_D);
            app.xmaxEditFieldLabel.Text = 'x max';
            app.OD_x_log_space = uiswitch(app.one_D, 'slider');
            app.OD_x_log_space.Items = {'lin', 'log'};
            app.OD_x_log_space.Value = 'lin';
            app.ComparetoDirectActuationCheckBox = uicheckbox(app.one_D);
            app.ComparetoDirectActuationCheckBox.Text = 'Compare to Direct Actuation';
            app.ComparetoDirectActuationCheckBox.Value = true;
            app.xminEditFieldLabel = uilabel(app.one_D);
            app.xminEditFieldLabel.Text = 'x min';
            app.OD_xmin = uieditfield(app.one_D, 'numeric');
            app.OD_xmin.Value = 0.01;
            app.OD_xmax = uieditfield(app.one_D, 'numeric');
            app.OD_xmax.Value = 12;
            app.heatmap = uitab(app.GraphingCorner);
            app.heatmap.Title = '2D Plot';
            app.a_maxCheckBox = uicheckbox(app.heatmap);
            app.a_maxCheckBox.Text = 'a_max';
            app.a_maxCheckBox.Value = true;
            app.xaxisLabel_2 = uilabel(app.heatmap);
            app.xaxisLabel_2.Text = 'x-axis';
            app.yaxisLabel = uilabel(app.heatmap);
            app.yaxisLabel.Text = 'y-axis';
            app.y_maxCheckBox = uicheckbox(app.heatmap);
            app.y_maxCheckBox.Text = 'y_max';
            app.y_unlatchCheckBox = uicheckbox(app.heatmap);
            app.y_unlatchCheckBox.Text = 'y_unlatch';
            app.t_LCheckBox = uicheckbox(app.heatmap);
            app.t_LCheckBox.Text = 't_L';
            app.v_toCheckBox = uicheckbox(app.heatmap);
            app.v_toCheckBox.Text = 'v_to';
            app.v_toCheckBox.Value = true;
            app.P_maxCheckBox = uicheckbox(app.heatmap);
            app.P_maxCheckBox.Text = 'P_max';
            app.P_maxCheckBox.Value = true;
            app.t_toCheckBox = uicheckbox(app.heatmap);
            app.t_toCheckBox.Text = 't_to';
            app.t_toCheckBox.Value = true;
            app.KE_maxCheckBox = uicheckbox(app.heatmap);
            app.KE_maxCheckBox.Text = 'KE_max';
            app.IV1DropDown = uidropdown(app.heatmap);
            app.IV1DropDown.Items = {'--no selection--'};
            app.IV1DropDown.ValueChangedFcn = createCallbackFcn(app, @IV1DropDownValueChanged, true);
            app.IV1DropDown.Tooltip = {'Independent Variable 1'};
            app.IV1DropDown.Value = '--no selection--';
            app.IV2DropDown = uidropdown(app.heatmap);
            app.IV2DropDown.Items = {'--no selection--'};
            app.IV2DropDown.ValueChangedFcn = createCallbackFcn(app, @IV2DropDownValueChanged, true);
            app.IV2DropDown.Value = '--no selection--';
            app.xminEditFieldLabel_2 = uilabel(app.heatmap);
            app.xminEditFieldLabel_2.Text = 'x min';
            app.xmin = uieditfield(app.heatmap, 'numeric');
            app.xmin.Value = 100;
            app.xmaxEditFieldLabel_2 = uilabel(app.heatmap);
            app.xmaxEditFieldLabel_2.Text = 'x max';
            app.xmax = uieditfield(app.heatmap, 'numeric');
            app.xmax.Value = 300000;
            app.yminLabel = uilabel(app.heatmap);
            app.yminLabel.Text = 'y min';
            app.ymin = uieditfield(app.heatmap, 'numeric');
            app.ymin.Value = 1;
            app.ymaxLabel = uilabel(app.heatmap);
            app.ymaxLabel.Text = 'y max';
            app.ymax = uieditfield(app.heatmap, 'numeric');
            app.ymax.Value = 1000;
            app.HeatmapOutputOptionsLabel = uilabel(app.heatmap);
            app.HeatmapOutputOptionsLabel.Text = 'Heatmap Output Options';
            app.pixelsofresolutionLabel_2 = uilabel(app.heatmap);
            app.pixelsofresolutionLabel_2.Text = 'pixels of resolution =';
            app.n = uieditfield(app.heatmap, 'numeric');
            app.n.Limits = [1 1024];
            app.n.RoundFractionalValues = 'on';
            app.n.ValueDisplayFormat = '%.0f';
            app.n.Value = 20;
            app.x_log_space = uiswitch(app.heatmap, 'slider');
            app.x_log_space.Items = {'lin', 'log'};
            app.x_log_space.Value = 'log';
            app.y_log_space = uiswitch(app.heatmap, 'slider');
            app.y_log_space.Items = {'lin', 'log'};
            app.y_log_space.Value = 'log';
            app.MakeLaMSAZonePlotCheckBox = uicheckbox(app.heatmap);
            app.MakeLaMSAZonePlotCheckBox.Text = 'Make LaMSA Zone Plot';
            app.MakeLaMSAZonePlotCheckBox.Value = true;
            app.GraphingCornerLabel = uilabel(app.UIFigure);
            app.GraphingCornerLabel.Text = 'Graphing Corner';
            app.GraphButton = uibutton(app.UIFigure, 'push');
            app.GraphButton.ButtonPushedFcn = createCallbackFcn(app, @GraphButtonPushed, true);
            app.GraphButton.Text = 'Graph!';
            app.savesolutionCheckBox = uicheckbox(app.UIFigure);
            app.savesolutionCheckBox.Text = 'save solution?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = practiceApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end