classdef practiceApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        yaxisDropDown       matlab.ui.control.DropDown
        yaxisDropDownLabel  matlab.ui.control.Label
        GoButton            matlab.ui.control.Button
        xaxisDropDown       matlab.ui.control.DropDown
        xaxisDropDownLabel  matlab.ui.control.Label
        SpringTabGroup      matlab.ui.container.TabGroup
        Tab                 matlab.ui.container.Tab
    end

    
    properties (Access = public)
        SpringTabs        = matlab.ui.container.Tab %list of tabs representing types of springs
        SpringEditFields  = matlab.ui.control.EditField %list of handles to dynamicaly generated edit fields
        SpringLabels      = matlab.ui.control.Label %list of handles to dynamicaly generated labels
    end
    
    properties (Access = private)
        SpringParamMap = containers.Map;
        SpringParamIndices = containers.Map;
        DDvars = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            files = dir(fullfile('Subclasses', '*.m'));
            delete(get(app.SpringTabGroup, 'Children'));
            
            yPos = 150;
            ind = 1;
            
            for file = files'
                [~, name, ~] = fileparts(file.name);
                
                tab = uitab(app.SpringTabGroup, 'Title', name);
                
                params = feval([name '.parameters']);
                app.SpringParamIndices(name) = [ind, ind + length(params) - 1];
                app.SpringParamMap(name) = params;

                for ii = 1:length(params)
                    paramName = params(1,ii);
                    defaultVal = params(2,ii);
                    app.SpringEditFields(ind) = uieditfield(tab);
                    app.SpringEditFields(ind).Position = [150 yPos-ii*25 148 22];
                    app.SpringEditFields(ind).Value = defaultVal;
                    
                    app.SpringLabels(ind) = uilabel(tab);
                    app.SpringLabels(ind).Text = paramName;
                    app.SpringLabels(ind).Position = [10 yPos-ii*25 150 22];

                    ind = ind + 1;
                end
                
            end
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            name = app.SpringTabGroup.SelectedTab.Title;
            index = app.SpringParamIndices(name);
            vals = {};
            for ii = index(1):index(2)
                val = str2double(app.SpringEditFields(ii).Value);
                vals{end + 1} = val;
            end
            spring = feval(name, vals{:});
            
        end

        % Selection change function: SpringTabGroup
        function SpringTabGroupSelectionChanged(app, event)
            selectedTab = app.SpringTabGroup.SelectedTab;
            params = app.SpringParamMap(selectedTab.Title);
            app.DDvars = params(1,:);
            app.xaxisDropDown.Items = app.DDvars;
            app.yaxisDropDown.Items = app.DDvars;
        end

        % Value changed function: xaxisDropDown
        function xaxisDropDownValueChanged(app, event)
            value = app.xaxisDropDown.Value;
            app.yaxisDropDown.Items = app.DDvars;
            app.yaxisDropDown.Items(ismember(app.yaxisDropDown.Items,value)) = [];
        end

        % Value changed function: yaxisDropDown
        function yaxisDropDownValueChanged(app, event)
            value = app.yaxisDropDown.Value;
            app.xaxisDropDown.Items = app.DDvars;
            app.xaxisDropDown.Items(ismember(app.xaxisDropDown.Items,value)) = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SpringTabGroup = uitabgroup(app.UIFigure);
            app.SpringTabGroup.SelectionChangedFcn = createCallbackFcn(app, @SpringTabGroupSelectionChanged, true);
            app.Tab = uitab(app.SpringTabGroup);
            app.Tab.Title = 'Tab2';
            app.xaxisDropDownLabel = uilabel(app.UIFigure);
            app.xaxisDropDownLabel.Text = 'x axis';
            app.xaxisDropDown = uidropdown(app.UIFigure);
            app.xaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @xaxisDropDownValueChanged, true);
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.yaxisDropDownLabel = uilabel(app.UIFigure);
            app.yaxisDropDownLabel.Text = 'y axis';
            app.yaxisDropDown = uidropdown(app.UIFigure);
            app.yaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @yaxisDropDownValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = practiceApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end